classdef ArsQ_RS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        Page1Tab                        matlab.ui.container.Tab
        SuivantButton                   matlab.ui.control.Button
        PasdutoutdaccordLabel           matlab.ui.control.Label
        PasdaccordLabel                 matlab.ui.control.Label
        PlusoumoinsdaccordLabel         matlab.ui.control.Label
        DaccordLabel                    matlab.ui.control.Label
        ToutfaitdaccordLabel            matlab.ui.control.Label
        JaipensmesmotionsLabel          matlab.ui.control.Label
        JemesuissentieagiteLabel        matlab.ui.control.Label
        JemesuissentiefatigueLabel      matlab.ui.control.Label
        JemesuissentieendormieLabel     matlab.ui.control.Label
        JemesuissentielaiseLabel        matlab.ui.control.Label
        JemesuissentiedtendueLabel      matlab.ui.control.Label
        JemesuissentieheureuxseLabel    matlab.ui.control.Label
        JemesuissentiemalLabel          matlab.ui.control.Label
        JaiapprecilasessionLabel        matlab.ui.control.Label
        JaieudesmotionsngativesLabel    matlab.ui.control.Label
        JemesuisennuyeLabel             matlab.ui.control.Label
        JenairiensentieLabel            matlab.ui.control.Label
        JairessentielammechosetoutaulongdelasessionLabel  matlab.ui.control.Label
        JaipensmasantLabel              matlab.ui.control.Label
        JaipensmontravailmestudesLabel  matlab.ui.control.Label
        JaipensmoncomportementLabel     matlab.ui.control.Label
        AmsterdamRestingStateQuestionnaireLabel  matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        ButtonGroup                     matlab.ui.container.ButtonGroup
        Button                          matlab.ui.control.RadioButton
        Button_2                        matlab.ui.control.RadioButton
        Button_3                        matlab.ui.control.RadioButton
        Button_4                        matlab.ui.control.RadioButton
        Button_5                        matlab.ui.control.RadioButton
        nButton                         matlab.ui.control.RadioButton
        ButtonGroup_2                   matlab.ui.container.ButtonGroup
        Button_6                        matlab.ui.control.RadioButton
        Button_7                        matlab.ui.control.RadioButton
        Button_8                        matlab.ui.control.RadioButton
        Button_9                        matlab.ui.control.RadioButton
        Button_10                       matlab.ui.control.RadioButton
        nButton_2                       matlab.ui.control.RadioButton
        ButtonGroup_3                   matlab.ui.container.ButtonGroup
        Button_11                       matlab.ui.control.RadioButton
        Button_12                       matlab.ui.control.RadioButton
        Button_13                       matlab.ui.control.RadioButton
        Button_14                       matlab.ui.control.RadioButton
        Button_15                       matlab.ui.control.RadioButton
        nButton_3                       matlab.ui.control.RadioButton
        ButtonGroup_4                   matlab.ui.container.ButtonGroup
        Button_16                       matlab.ui.control.RadioButton
        Button_17                       matlab.ui.control.RadioButton
        Button_18                       matlab.ui.control.RadioButton
        Button_19                       matlab.ui.control.RadioButton
        Button_20                       matlab.ui.control.RadioButton
        nButton_4                       matlab.ui.control.RadioButton
        ButtonGroup_5                   matlab.ui.container.ButtonGroup
        Button_21                       matlab.ui.control.RadioButton
        Button_22                       matlab.ui.control.RadioButton
        Button_23                       matlab.ui.control.RadioButton
        Button_24                       matlab.ui.control.RadioButton
        Button_25                       matlab.ui.control.RadioButton
        nButton_5                       matlab.ui.control.RadioButton
        ButtonGroup_6                   matlab.ui.container.ButtonGroup
        Button_26                       matlab.ui.control.RadioButton
        Button_27                       matlab.ui.control.RadioButton
        Button_28                       matlab.ui.control.RadioButton
        Button_29                       matlab.ui.control.RadioButton
        Button_30                       matlab.ui.control.RadioButton
        nButton_6                       matlab.ui.control.RadioButton
        ButtonGroup_7                   matlab.ui.container.ButtonGroup
        Button_31                       matlab.ui.control.RadioButton
        Button_32                       matlab.ui.control.RadioButton
        Button_33                       matlab.ui.control.RadioButton
        Button_34                       matlab.ui.control.RadioButton
        Button_35                       matlab.ui.control.RadioButton
        nButton_7                       matlab.ui.control.RadioButton
        ButtonGroup_8                   matlab.ui.container.ButtonGroup
        Button_36                       matlab.ui.control.RadioButton
        Button_37                       matlab.ui.control.RadioButton
        Button_38                       matlab.ui.control.RadioButton
        Button_39                       matlab.ui.control.RadioButton
        Button_40                       matlab.ui.control.RadioButton
        nButton_8                       matlab.ui.control.RadioButton
        ButtonGroup_9                   matlab.ui.container.ButtonGroup
        Button_41                       matlab.ui.control.RadioButton
        Button_42                       matlab.ui.control.RadioButton
        Button_43                       matlab.ui.control.RadioButton
        Button_44                       matlab.ui.control.RadioButton
        Button_45                       matlab.ui.control.RadioButton
        nButton_9                       matlab.ui.control.RadioButton
        ButtonGroup_10                  matlab.ui.container.ButtonGroup
        Button_46                       matlab.ui.control.RadioButton
        Button_47                       matlab.ui.control.RadioButton
        Button_48                       matlab.ui.control.RadioButton
        Button_49                       matlab.ui.control.RadioButton
        Button_50                       matlab.ui.control.RadioButton
        nButton_10                      matlab.ui.control.RadioButton
        ButtonGroup_11                  matlab.ui.container.ButtonGroup
        Button_51                       matlab.ui.control.RadioButton
        Button_52                       matlab.ui.control.RadioButton
        Button_53                       matlab.ui.control.RadioButton
        Button_54                       matlab.ui.control.RadioButton
        Button_55                       matlab.ui.control.RadioButton
        nButton_11                      matlab.ui.control.RadioButton
        ButtonGroup_12                  matlab.ui.container.ButtonGroup
        Button_56                       matlab.ui.control.RadioButton
        Button_57                       matlab.ui.control.RadioButton
        Button_58                       matlab.ui.control.RadioButton
        Button_59                       matlab.ui.control.RadioButton
        Button_60                       matlab.ui.control.RadioButton
        nButton_12                      matlab.ui.control.RadioButton
        ButtonGroup_13                  matlab.ui.container.ButtonGroup
        Button_61                       matlab.ui.control.RadioButton
        Button_62                       matlab.ui.control.RadioButton
        Button_63                       matlab.ui.control.RadioButton
        Button_64                       matlab.ui.control.RadioButton
        Button_65                       matlab.ui.control.RadioButton
        nButton_13                      matlab.ui.control.RadioButton
        ButtonGroup_14                  matlab.ui.container.ButtonGroup
        Button_66                       matlab.ui.control.RadioButton
        Button_67                       matlab.ui.control.RadioButton
        Button_68                       matlab.ui.control.RadioButton
        Button_69                       matlab.ui.control.RadioButton
        Button_70                       matlab.ui.control.RadioButton
        nButton_14                      matlab.ui.control.RadioButton
        ButtonGroup_15                  matlab.ui.container.ButtonGroup
        Button_71                       matlab.ui.control.RadioButton
        Button_72                       matlab.ui.control.RadioButton
        Button_73                       matlab.ui.control.RadioButton
        Button_74                       matlab.ui.control.RadioButton
        Button_75                       matlab.ui.control.RadioButton
        nButton_15                      matlab.ui.control.RadioButton
        ButtonGroup_16                  matlab.ui.container.ButtonGroup
        Button_76                       matlab.ui.control.RadioButton
        Button_77                       matlab.ui.control.RadioButton
        Button_78                       matlab.ui.control.RadioButton
        Button_79                       matlab.ui.control.RadioButton
        Button_80                       matlab.ui.control.RadioButton
        nButton_16                      matlab.ui.control.RadioButton
        Label_2                         matlab.ui.control.Label
        Page2Tab                        matlab.ui.container.Tab
        PasdutoutdaccordLabel_2         matlab.ui.control.Label
        PasdaccordLabel_2               matlab.ui.control.Label
        PlusoumoinsdaccordLabel_2       matlab.ui.control.Label
        DaccordLabel_2                  matlab.ui.control.Label
        ToutfaitdaccordLabel_2          matlab.ui.control.Label
        JaieudespensesquejenauraispasvolontierspartagavecdautresLabel  matlab.ui.control.Label
        JtaisoccuppenserLabel           matlab.ui.control.Label
        JavaisdespensessimilairestoutaulongdelasessionLabel  matlab.ui.control.Label
        JaipensauxautresLabel           matlab.ui.control.Label
        JaipensmoimmeLabel              matlab.ui.control.Label
        JaipensdeschosesagrablesLabel   matlab.ui.control.Label
        JecontrlaismespensesLabel       matlab.ui.control.Label
        JaipensrsoudredesproblmesLabel  matlab.ui.control.Label
        JaipensaubutdelexprienceLabel   matlab.ui.control.Label
        JaieudumalresterveilleLabel     matlab.ui.control.Label
        JaieudespensesquichangaientrapidementLabel  matlab.ui.control.Label
        JaieudespensessuperficiellesLabel  matlab.ui.control.Label
        JaipensaupassLabel              matlab.ui.control.Label
        JaipensauprsentLabel            matlab.ui.control.Label
        JaipensaufuturLabel             matlab.ui.control.Label
        JaieudespensesprofondesLabel    matlab.ui.control.Label
        SuivantButton_2                 matlab.ui.control.Button
        ButtonGroup_17                  matlab.ui.container.ButtonGroup
        Button_81                       matlab.ui.control.RadioButton
        Button_82                       matlab.ui.control.RadioButton
        Button_83                       matlab.ui.control.RadioButton
        Button_84                       matlab.ui.control.RadioButton
        Button_85                       matlab.ui.control.RadioButton
        nButton_17                      matlab.ui.control.RadioButton
        ButtonGroup_18                  matlab.ui.container.ButtonGroup
        Button_86                       matlab.ui.control.RadioButton
        Button_87                       matlab.ui.control.RadioButton
        Button_88                       matlab.ui.control.RadioButton
        Button_89                       matlab.ui.control.RadioButton
        Button_90                       matlab.ui.control.RadioButton
        nButton_18                      matlab.ui.control.RadioButton
        ButtonGroup_19                  matlab.ui.container.ButtonGroup
        Button_91                       matlab.ui.control.RadioButton
        Button_92                       matlab.ui.control.RadioButton
        Button_93                       matlab.ui.control.RadioButton
        Button_94                       matlab.ui.control.RadioButton
        Button_95                       matlab.ui.control.RadioButton
        nButton_19                      matlab.ui.control.RadioButton
        ButtonGroup_20                  matlab.ui.container.ButtonGroup
        Button_96                       matlab.ui.control.RadioButton
        Button_97                       matlab.ui.control.RadioButton
        Button_98                       matlab.ui.control.RadioButton
        Button_99                       matlab.ui.control.RadioButton
        Button_100                      matlab.ui.control.RadioButton
        nButton_20                      matlab.ui.control.RadioButton
        ButtonGroup_21                  matlab.ui.container.ButtonGroup
        Button_101                      matlab.ui.control.RadioButton
        Button_102                      matlab.ui.control.RadioButton
        Button_103                      matlab.ui.control.RadioButton
        Button_104                      matlab.ui.control.RadioButton
        Button_105                      matlab.ui.control.RadioButton
        nButton_21                      matlab.ui.control.RadioButton
        ButtonGroup_22                  matlab.ui.container.ButtonGroup
        Button_106                      matlab.ui.control.RadioButton
        Button_107                      matlab.ui.control.RadioButton
        Button_108                      matlab.ui.control.RadioButton
        Button_109                      matlab.ui.control.RadioButton
        Button_110                      matlab.ui.control.RadioButton
        nButton_22                      matlab.ui.control.RadioButton
        ButtonGroup_23                  matlab.ui.container.ButtonGroup
        Button_111                      matlab.ui.control.RadioButton
        Button_112                      matlab.ui.control.RadioButton
        Button_113                      matlab.ui.control.RadioButton
        Button_114                      matlab.ui.control.RadioButton
        Button_115                      matlab.ui.control.RadioButton
        nButton_23                      matlab.ui.control.RadioButton
        ButtonGroup_24                  matlab.ui.container.ButtonGroup
        Button_116                      matlab.ui.control.RadioButton
        Button_117                      matlab.ui.control.RadioButton
        Button_118                      matlab.ui.control.RadioButton
        Button_119                      matlab.ui.control.RadioButton
        Button_120                      matlab.ui.control.RadioButton
        nButton_24                      matlab.ui.control.RadioButton
        ButtonGroup_25                  matlab.ui.container.ButtonGroup
        Button_121                      matlab.ui.control.RadioButton
        Button_122                      matlab.ui.control.RadioButton
        Button_123                      matlab.ui.control.RadioButton
        Button_124                      matlab.ui.control.RadioButton
        Button_125                      matlab.ui.control.RadioButton
        nButton_25                      matlab.ui.control.RadioButton
        ButtonGroup_26                  matlab.ui.container.ButtonGroup
        Button_126                      matlab.ui.control.RadioButton
        Button_127                      matlab.ui.control.RadioButton
        Button_128                      matlab.ui.control.RadioButton
        Button_129                      matlab.ui.control.RadioButton
        Button_130                      matlab.ui.control.RadioButton
        nButton_26                      matlab.ui.control.RadioButton
        ButtonGroup_27                  matlab.ui.container.ButtonGroup
        Button_131                      matlab.ui.control.RadioButton
        Button_132                      matlab.ui.control.RadioButton
        Button_133                      matlab.ui.control.RadioButton
        Button_134                      matlab.ui.control.RadioButton
        Button_135                      matlab.ui.control.RadioButton
        nButton_27                      matlab.ui.control.RadioButton
        ButtonGroup_28                  matlab.ui.container.ButtonGroup
        Button_136                      matlab.ui.control.RadioButton
        Button_137                      matlab.ui.control.RadioButton
        Button_138                      matlab.ui.control.RadioButton
        Button_139                      matlab.ui.control.RadioButton
        Button_140                      matlab.ui.control.RadioButton
        nButton_28                      matlab.ui.control.RadioButton
        ButtonGroup_29                  matlab.ui.container.ButtonGroup
        Button_141                      matlab.ui.control.RadioButton
        Button_142                      matlab.ui.control.RadioButton
        Button_143                      matlab.ui.control.RadioButton
        Button_144                      matlab.ui.control.RadioButton
        Button_145                      matlab.ui.control.RadioButton
        nButton_29                      matlab.ui.control.RadioButton
        ButtonGroup_30                  matlab.ui.container.ButtonGroup
        Button_146                      matlab.ui.control.RadioButton
        Button_147                      matlab.ui.control.RadioButton
        Button_148                      matlab.ui.control.RadioButton
        Button_149                      matlab.ui.control.RadioButton
        Button_150                      matlab.ui.control.RadioButton
        nButton_30                      matlab.ui.control.RadioButton
        ButtonGroup_31                  matlab.ui.container.ButtonGroup
        Button_151                      matlab.ui.control.RadioButton
        Button_152                      matlab.ui.control.RadioButton
        Button_153                      matlab.ui.control.RadioButton
        Button_154                      matlab.ui.control.RadioButton
        Button_155                      matlab.ui.control.RadioButton
        nButton_31                      matlab.ui.control.RadioButton
        ButtonGroup_32                  matlab.ui.container.ButtonGroup
        Button_156                      matlab.ui.control.RadioButton
        Button_157                      matlab.ui.control.RadioButton
        Button_158                      matlab.ui.control.RadioButton
        Button_159                      matlab.ui.control.RadioButton
        Button_160                      matlab.ui.control.RadioButton
        nButton_32                      matlab.ui.control.RadioButton
        Label_20                        matlab.ui.control.Label
        JenepensaisrienLabel            matlab.ui.control.Label
        ButtonGroup_50                  matlab.ui.container.ButtonGroup
        Button_246                      matlab.ui.control.RadioButton
        Button_247                      matlab.ui.control.RadioButton
        Button_248                      matlab.ui.control.RadioButton
        Button_249                      matlab.ui.control.RadioButton
        Button_250                      matlab.ui.control.RadioButton
        nButton_50                      matlab.ui.control.RadioButton
        Page3Tab                        matlab.ui.container.Tab
        PasdutoutdaccordLabel_3         matlab.ui.control.Label
        PasdaccordLabel_3               matlab.ui.control.Label
        PlusoumoinsdaccordLabel_3       matlab.ui.control.Label
        DaccordLabel_3                  matlab.ui.control.Label
        ToutfaitdaccordLabel_3          matlab.ui.control.Label
        JaieudesdifficultsretenirmespensesLabel  matlab.ui.control.Label
        JaipensauxpersonnesquejaimebienLabel  matlab.ui.control.Label
        JaipensenimagesLabel            matlab.ui.control.Label
        JaipensenmotsLabel              matlab.ui.control.Label
        JaipensauxchosesquejedoisfaireLabel  matlab.ui.control.Label
        JtaisconscientdemoncorpsLabel   matlab.ui.control.Label
        JaipensauxsonsquimentourentLabel  matlab.ui.control.Label
        JaipensauxodeursautourdemoiLabel  matlab.ui.control.Label
        JaipensauxbtementsdemoncoeurLabel  matlab.ui.control.Label
        JaipensmarespirationLabel       matlab.ui.control.Label
        JairessentideladouleurLabel     matlab.ui.control.Label
        JemesuismislaplacedesautresLabel  matlab.ui.control.Label
        JemesuissentimotivpourparticiperLabel  matlab.ui.control.Label
        JaidesdifficultsmesouvenirdemespensesLabel  matlab.ui.control.Label
        JaidesdifficultsmesouvenirdemessentimentsLabel  matlab.ui.control.Label
        JavaislesyeuxfermsLabel         matlab.ui.control.Label
        JaipurpondrecorrectementauxaffirmationsprcdentesLabel  matlab.ui.control.Label
        TerminerButton                  matlab.ui.control.Button
        ButtonGroup_33                  matlab.ui.container.ButtonGroup
        Button_161                      matlab.ui.control.RadioButton
        Button_162                      matlab.ui.control.RadioButton
        Button_163                      matlab.ui.control.RadioButton
        Button_164                      matlab.ui.control.RadioButton
        Button_165                      matlab.ui.control.RadioButton
        nButton_33                      matlab.ui.control.RadioButton
        ButtonGroup_34                  matlab.ui.container.ButtonGroup
        Button_166                      matlab.ui.control.RadioButton
        Button_167                      matlab.ui.control.RadioButton
        Button_168                      matlab.ui.control.RadioButton
        Button_169                      matlab.ui.control.RadioButton
        Button_170                      matlab.ui.control.RadioButton
        nButton_34                      matlab.ui.control.RadioButton
        ButtonGroup_35                  matlab.ui.container.ButtonGroup
        Button_171                      matlab.ui.control.RadioButton
        Button_172                      matlab.ui.control.RadioButton
        Button_173                      matlab.ui.control.RadioButton
        Button_174                      matlab.ui.control.RadioButton
        Button_175                      matlab.ui.control.RadioButton
        nButton_35                      matlab.ui.control.RadioButton
        ButtonGroup_36                  matlab.ui.container.ButtonGroup
        Button_176                      matlab.ui.control.RadioButton
        Button_177                      matlab.ui.control.RadioButton
        Button_178                      matlab.ui.control.RadioButton
        Button_179                      matlab.ui.control.RadioButton
        Button_180                      matlab.ui.control.RadioButton
        nButton_36                      matlab.ui.control.RadioButton
        ButtonGroup_37                  matlab.ui.container.ButtonGroup
        Button_181                      matlab.ui.control.RadioButton
        Button_182                      matlab.ui.control.RadioButton
        Button_183                      matlab.ui.control.RadioButton
        Button_184                      matlab.ui.control.RadioButton
        Button_185                      matlab.ui.control.RadioButton
        nButton_37                      matlab.ui.control.RadioButton
        ButtonGroup_38                  matlab.ui.container.ButtonGroup
        Button_186                      matlab.ui.control.RadioButton
        Button_187                      matlab.ui.control.RadioButton
        Button_188                      matlab.ui.control.RadioButton
        Button_189                      matlab.ui.control.RadioButton
        Button_190                      matlab.ui.control.RadioButton
        nButton_38                      matlab.ui.control.RadioButton
        ButtonGroup_39                  matlab.ui.container.ButtonGroup
        Button_191                      matlab.ui.control.RadioButton
        Button_192                      matlab.ui.control.RadioButton
        Button_193                      matlab.ui.control.RadioButton
        Button_194                      matlab.ui.control.RadioButton
        Button_195                      matlab.ui.control.RadioButton
        nButton_39                      matlab.ui.control.RadioButton
        ButtonGroup_40                  matlab.ui.container.ButtonGroup
        Button_196                      matlab.ui.control.RadioButton
        Button_197                      matlab.ui.control.RadioButton
        Button_198                      matlab.ui.control.RadioButton
        Button_199                      matlab.ui.control.RadioButton
        Button_200                      matlab.ui.control.RadioButton
        nButton_40                      matlab.ui.control.RadioButton
        ButtonGroup_41                  matlab.ui.container.ButtonGroup
        Button_201                      matlab.ui.control.RadioButton
        Button_202                      matlab.ui.control.RadioButton
        Button_203                      matlab.ui.control.RadioButton
        Button_204                      matlab.ui.control.RadioButton
        Button_205                      matlab.ui.control.RadioButton
        nButton_41                      matlab.ui.control.RadioButton
        ButtonGroup_42                  matlab.ui.container.ButtonGroup
        Button_206                      matlab.ui.control.RadioButton
        Button_207                      matlab.ui.control.RadioButton
        Button_208                      matlab.ui.control.RadioButton
        Button_209                      matlab.ui.control.RadioButton
        Button_210                      matlab.ui.control.RadioButton
        nButton_42                      matlab.ui.control.RadioButton
        ButtonGroup_43                  matlab.ui.container.ButtonGroup
        Button_211                      matlab.ui.control.RadioButton
        Button_212                      matlab.ui.control.RadioButton
        Button_213                      matlab.ui.control.RadioButton
        Button_214                      matlab.ui.control.RadioButton
        Button_215                      matlab.ui.control.RadioButton
        nButton_43                      matlab.ui.control.RadioButton
        ButtonGroup_44                  matlab.ui.container.ButtonGroup
        Button_216                      matlab.ui.control.RadioButton
        Button_217                      matlab.ui.control.RadioButton
        Button_218                      matlab.ui.control.RadioButton
        Button_219                      matlab.ui.control.RadioButton
        Button_220                      matlab.ui.control.RadioButton
        nButton_44                      matlab.ui.control.RadioButton
        ButtonGroup_45                  matlab.ui.container.ButtonGroup
        Button_221                      matlab.ui.control.RadioButton
        Button_222                      matlab.ui.control.RadioButton
        Button_223                      matlab.ui.control.RadioButton
        Button_224                      matlab.ui.control.RadioButton
        Button_225                      matlab.ui.control.RadioButton
        nButton_45                      matlab.ui.control.RadioButton
        ButtonGroup_46                  matlab.ui.container.ButtonGroup
        Button_226                      matlab.ui.control.RadioButton
        Button_227                      matlab.ui.control.RadioButton
        Button_228                      matlab.ui.control.RadioButton
        Button_229                      matlab.ui.control.RadioButton
        Button_230                      matlab.ui.control.RadioButton
        nButton_46                      matlab.ui.control.RadioButton
        ButtonGroup_47                  matlab.ui.container.ButtonGroup
        Button_231                      matlab.ui.control.RadioButton
        Button_232                      matlab.ui.control.RadioButton
        Button_233                      matlab.ui.control.RadioButton
        Button_234                      matlab.ui.control.RadioButton
        Button_235                      matlab.ui.control.RadioButton
        nButton_47                      matlab.ui.control.RadioButton
        ButtonGroup_48                  matlab.ui.container.ButtonGroup
        Button_236                      matlab.ui.control.RadioButton
        Button_237                      matlab.ui.control.RadioButton
        Button_238                      matlab.ui.control.RadioButton
        Button_239                      matlab.ui.control.RadioButton
        Button_240                      matlab.ui.control.RadioButton
        nButton_48                      matlab.ui.control.RadioButton
        ButtonGroup_49                  matlab.ui.container.ButtonGroup
        Button_241                      matlab.ui.control.RadioButton
        Button_242                      matlab.ui.control.RadioButton
        Button_243                      matlab.ui.control.RadioButton
        Button_244                      matlab.ui.control.RadioButton
        Button_245                      matlab.ui.control.RadioButton
        nButton_49                      matlab.ui.control.RadioButton
        attentionLabel                  matlab.ui.control.Label
    end

    
    properties (Access = private)
        SSID;
        %MainApp;
        path;
        questionnaire_file;
        participant_answers= [] ;  %'Identifiant','Q1','Q2','Q3','Q4','Q5','Q6',...,
        tab1 = false;
        tab2 = false;
        tab3 = false;
        answers_1;
        answers_2;
        answers_3;
        questions;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, SSID, data_path)
            app.SSID = SSID;
            app.path = data_path;
            %app.MainApp = mainapp;
            app.questionnaire_file = strcat(app.path,'\QuestionnaireArsQ.xls');
            app.questions = string(["J'ai pensé à mes émotions.","Je me suis senti.e agité.e.","Je me suis senti.e fatigué.e.","Je me suis senti.e endormi.e.","Je me suis senti.e à l'aise.", "Je me suis senti.e détendu.e.","Je me suis senti.e heureux.se","Je me suis senti.e mal.","J'ai apprecié la session.","J'ai eu des émotions négatives.","Je me suis ennuyé.e.", "Je n'ai rien senti.e.","J'ai ressenti.e la même chose tout au long de la session.","J'ai pensé à ma santé.","J'ai pensé à mon travail / mes études.","J'ai pensé à mon comportement.","J'ai eu des pensées que je n'aurais pas volontiers partagé avec d'autres.","J'étais occupé à penser.","J'avais des pensées similaires tout au long de la session.","J'ai pensé aux autres.","J'ai pensé à moi-même.","J'ai pensé à des choses agréables.","Je contrôlais mes pensées.","J'ai pensé à résoudre des problèmes.","J'ai pensé au but de l'expérience.","J'ai eu du mal à rester éveillé.e.","J'ai eu des pensées qui changaient rapidement.","J'ai eu des pensées superficielles.","J'ai pensé au passé.","J'ai pensé au présent.","J'ai pensé au futur.","J'ai eu des pensées profondes.","Je ne pensais à rien.","J'ai eu des difficultés à retenir mes pensées.","J'ai pensé aux personnes que j'aime bien.","J'ai pensé en images.","J'ai pensé en mots.","J'ai pensé aux choses que je dois faire.","J'étais conscient de mon corps.","J'ai pensé aux sons qui m'entourent.","J'ai pensé aux odeurs autour de moi.","J'ai pensé aux bâtements de mon coeur.","J'ai pensé à ma respiration.", "J'ai ressenti de la douleur.","Je me suis mis à la place des autres.","Je me suis senti motivé pour participer.","J'ai des difficultés à me souvenir de mes pensées.","J'ai des difficultés à me souvenir de mes sentiments.","J'avais les yeux fermés.","J'ai pu répondre correctement aux affirmations précédentes"]);
            if ~isfile(app.questionnaire_file) %create empty file
                xlswrite(app.questionnaire_file,['Identifiant','Date',app.questions]);
            end
        end

        % Button pushed function: SuivantButton
        function SuivantButtonPushed(app, event)
            app.TabGroup.SelectedTab= app.Page2Tab;
        end

        % Button pushed function: SuivantButton_2
        function SuivantButton_2Pushed(app, event)
            app.TabGroup.SelectedTab= app.Page3Tab;
        end

        % Selection changed function: ButtonGroup, ButtonGroup_10, 
        % ButtonGroup_11, ButtonGroup_12, ButtonGroup_13, 
        % ButtonGroup_14, ButtonGroup_15, ButtonGroup_16, 
        % ButtonGroup_2, ButtonGroup_3, ButtonGroup_4, 
        % ButtonGroup_5, ButtonGroup_6, ButtonGroup_7, 
        % ButtonGroup_8, ButtonGroup_9
        function ButtonGroup_Tab1_SelectionChanged(app, event)
            app.answers_1 = string({app.ButtonGroup.SelectedObject.Text,app.ButtonGroup_2.SelectedObject.Text,app.ButtonGroup_3.SelectedObject.Text,app.ButtonGroup_4.SelectedObject.Text,app.ButtonGroup_5.SelectedObject.Text,app.ButtonGroup_6.SelectedObject.Text,app.ButtonGroup_7.SelectedObject.Text,app.ButtonGroup_8.SelectedObject.Text,app.ButtonGroup_9.SelectedObject.Text,app.ButtonGroup_10.SelectedObject.Text,app.ButtonGroup_11.SelectedObject.Text,app.ButtonGroup_12.SelectedObject.Text,app.ButtonGroup_13.SelectedObject.Text,app.ButtonGroup_14.SelectedObject.Text,app.ButtonGroup_15.SelectedObject.Text,app.ButtonGroup_16.SelectedObject.Text});
            if ~any(strcmp(app.answers_1,"n"))    
                app.SuivantButton.Enable = true;
                app.tab1 = true;
            else
                app.SuivantButton.Enable = false;
                app.tab1 = false;
            end
            if app.tab1 == true & app.tab2 == true & app.tab3 == true
                app.TerminerButton.Enable = true;
                app.attentionLabel.Visible = "off";
            else
                app.attentionLabel.Visible = "on";
            end
        end

        % Selection changed function: ButtonGroup_17, 
        % ButtonGroup_18, ButtonGroup_19, ButtonGroup_20, 
        % ButtonGroup_21, ButtonGroup_22, ButtonGroup_23, 
        % ButtonGroup_24, ButtonGroup_25, ButtonGroup_26, 
        % ButtonGroup_27, ButtonGroup_28, ButtonGroup_29, 
        % ButtonGroup_30, ButtonGroup_31, ButtonGroup_32
        function ButtonGroup_Tab2_SelectionChanged(app, event)
            app.answers_2 = string({app.ButtonGroup_17.SelectedObject.Text,app.ButtonGroup_18.SelectedObject.Text , app.ButtonGroup_19.SelectedObject.Text , app.ButtonGroup_20.SelectedObject.Text , app.ButtonGroup_21.SelectedObject.Text , app.ButtonGroup_22.SelectedObject.Text , app.ButtonGroup_23.SelectedObject.Text , app.ButtonGroup_24.SelectedObject.Text , app.ButtonGroup_25.SelectedObject.Text , app.ButtonGroup_26.SelectedObject.Text , app.ButtonGroup_27.SelectedObject.Text , app.ButtonGroup_28.SelectedObject.Text , app.ButtonGroup_29.SelectedObject.Text , app.ButtonGroup_30.SelectedObject.Text , app.ButtonGroup_31.SelectedObject.Text,app.ButtonGroup_32.SelectedObject.Text,app.ButtonGroup_50.SelectedObject.Text});
            if ~any(strcmp(app.answers_2,"n"))    
                app.SuivantButton_2.Enable = true;
                app.tab2 = true;
            else
                app.SuivantButton_2.Enable = false;
                app.tab2 = false;
            end  
            if app.tab1 == true & app.tab2 == true & app.tab3 == true
                app.TerminerButton.Enable = true;
                app.attentionLabel.Visible = "off";
            else
                app.attentionLabel.Visible = "on";
            end
        end

        % Selection changed function: ButtonGroup_33, 
        % ButtonGroup_34, ButtonGroup_35, ButtonGroup_36, 
        % ButtonGroup_37, ButtonGroup_38, ButtonGroup_39, 
        % ButtonGroup_40, ButtonGroup_41, ButtonGroup_42, 
        % ButtonGroup_43, ButtonGroup_44, ButtonGroup_45, 
        % ButtonGroup_46, ButtonGroup_47, ButtonGroup_48, 
        % ButtonGroup_49
        function ButtonGroup_Tab3_SelectionChanged(app, event)
            app.answers_3 = string({app.ButtonGroup_33.SelectedObject.Text, app.ButtonGroup_34.SelectedObject.Text , app.ButtonGroup_35.SelectedObject.Text , app.ButtonGroup_36.SelectedObject.Text , app.ButtonGroup_37.SelectedObject.Text , app.ButtonGroup_38.SelectedObject.Text , app.ButtonGroup_39.SelectedObject.Text , app.ButtonGroup_40.SelectedObject.Text , app.ButtonGroup_41.SelectedObject.Text , app.ButtonGroup_42.SelectedObject.Text , app.ButtonGroup_43.SelectedObject.Text , app.ButtonGroup_44.SelectedObject.Text , app.ButtonGroup_45.SelectedObject.Text , app.ButtonGroup_46.SelectedObject.Text , app.ButtonGroup_47.SelectedObject.Text , app.ButtonGroup_48.SelectedObject.Text , app.ButtonGroup_49.SelectedObject.Text});
            if ~any(strcmp(app.answers_3,"n"))    
                app.tab3 = true;
            else
                app.tab3 = false;
                app.attentionLabel.Visible = "on";
            end  
            if app.tab1 == true & app.tab2 == true & app.tab3 == true
                app.TerminerButton.Enable = true;
                app.attentionLabel.Visible = "off";
            else
                app.attentionLabel.Visible = "on";
            end
        end

        % Button pushed function: TerminerButton
        function TerminerButtonPushed(app, event)
            if app.tab1 == true & app.tab2 == true & app.tab3 == true
                behav_folder = strcat(app.path,"\sub-",app.SSID,"\ses-1\behav\");
                mkdir(behav_folder);
                app.attentionLabel.Visible = "off";
                app.participant_answers  = [app.SSID date app.answers_1 app.answers_2 app.answers_3];
                writematrix(app.participant_answers,app.questionnaire_file,'WriteMode','append'); %Add Participant Info to excel file
                %table by participant
                %write table by participant
                t.Question = app.questions';
                t.Answer = [app.answers_1 app.answers_2 app.answers_3]';
                %disp(t);
                likert_file = struct2table(t);
                writetable(likert_file,strcat(behav_folder,app.SSID,"_ArsQuestionnaire.txt"),'Delimiter',';','Encoding','UTF-8');
                
            else
                app.attentionLabel.Visible = "on";
            end
            delete(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Page1Tab = uitab(app.TabGroup);
            app.Page1Tab.Title = 'Page 1';
            app.SuivantButton = uibutton(app.Page1Tab, 'push');
            app.SuivantButton.ButtonPushedFcn = createCallbackFcn(app, @SuivantButtonPushed, true);
            app.SuivantButton.Enable = 'off';
            app.SuivantButton.Text = 'Suivant';
            app.PasdutoutdaccordLabel = uilabel(app.Page1Tab);
            app.PasdutoutdaccordLabel.Text = 'Pas du tout d''accord';
            app.PasdaccordLabel = uilabel(app.Page1Tab);
            app.PasdaccordLabel.Text = 'Pas d''accord';
            app.PlusoumoinsdaccordLabel = uilabel(app.Page1Tab);
            app.PlusoumoinsdaccordLabel.Text = 'Plus ou moins d''accord';
            app.DaccordLabel = uilabel(app.Page1Tab);
            app.DaccordLabel.Text = 'D''accord';
            app.ToutfaitdaccordLabel = uilabel(app.Page1Tab);
            app.ToutfaitdaccordLabel.Text = 'Tout à fait d''accord';
            app.JaipensmesmotionsLabel = uilabel(app.Page1Tab);
            app.JaipensmesmotionsLabel.Text = 'J''ai pensé à mes émotions.';
            app.JemesuissentieagiteLabel = uilabel(app.Page1Tab);
            app.JemesuissentieagiteLabel.Text = 'Je me suis senti.e agité.e.';
            app.JemesuissentiefatigueLabel = uilabel(app.Page1Tab);
            app.JemesuissentiefatigueLabel.Text = 'Je me suis senti.e fatigué.e.';
            app.JemesuissentieendormieLabel = uilabel(app.Page1Tab);
            app.JemesuissentieendormieLabel.Text = 'Je me suis senti.e endormi.e.';
            app.JemesuissentielaiseLabel = uilabel(app.Page1Tab);
            app.JemesuissentielaiseLabel.Text = 'Je me suis senti.e à l''aise.';
            app.JemesuissentiedtendueLabel = uilabel(app.Page1Tab);
            app.JemesuissentiedtendueLabel.Text = 'Je me suis senti·e détendu.e.';
            app.JemesuissentieheureuxseLabel = uilabel(app.Page1Tab);
            app.JemesuissentieheureuxseLabel.Text = 'Je me suis senti·e heureux.se.';
            app.JemesuissentiemalLabel = uilabel(app.Page1Tab);
            app.JemesuissentiemalLabel.Text = 'Je me suis senti·e mal.';
            app.JaiapprecilasessionLabel = uilabel(app.Page1Tab);
            app.JaiapprecilasessionLabel.Text = 'J''ai apprecié la session.';
            app.JaieudesmotionsngativesLabel = uilabel(app.Page1Tab);
            app.JaieudesmotionsngativesLabel.Text = 'J''ai eu des émotions négatives.';
            app.JemesuisennuyeLabel = uilabel(app.Page1Tab);
            app.JemesuisennuyeLabel.Text = 'Je me suis ennuyé.e.';
            app.JenairiensentieLabel = uilabel(app.Page1Tab);
            app.JenairiensentieLabel.Text = 'Je n''ai rien senti.e.';
            app.JairessentielammechosetoutaulongdelasessionLabel = uilabel(app.Page1Tab);
            app.JairessentielammechosetoutaulongdelasessionLabel.Text = 'J''ai ressenti.e la même chose tout au long de la session.';
            app.JaipensmasantLabel = uilabel(app.Page1Tab);
            app.JaipensmasantLabel.Text = 'J''ai pensé à ma santé.';
            app.JaipensmontravailmestudesLabel = uilabel(app.Page1Tab);
            app.JaipensmontravailmestudesLabel.Text = 'J''ai pensé à mon travail / mes études.';
            app.JaipensmoncomportementLabel = uilabel(app.Page1Tab);
            app.JaipensmoncomportementLabel.Text = 'J''ai pensé à mon comportement.';
            app.AmsterdamRestingStateQuestionnaireLabel = uilabel(app.Page1Tab);
            app.AmsterdamRestingStateQuestionnaireLabel.Text = 'Amsterdam Resting-State Questionnaire';
            app.Label = uilabel(app.Page1Tab);
            app.Label.Text = 'Veuillez répondre à chaque question en cochant un chiffre pour indiquer dans quelle mesure vous êtes d''accord avec chaque affirmation. Répondez à toutes les questions même si vous n''êtes pas sûr de votre réponse. Appelez l''expérimentateur si vous avez des questions.';
            app.ButtonGroup = uibuttongroup(app.Page1Tab);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_Tab1_SelectionChanged, true);
            app.Button = uiradiobutton(app.ButtonGroup);
            app.Button.Text = '-2';
            app.Button_2 = uiradiobutton(app.ButtonGroup);
            app.Button_2.Text = '-1';
            app.Button_3 = uiradiobutton(app.ButtonGroup);
            app.Button_3.Text = '0';
            app.Button_4 = uiradiobutton(app.ButtonGroup);
            app.Button_4.Text = '1';
            app.Button_5 = uiradiobutton(app.ButtonGroup);
            app.Button_5.Text = '2';
            app.nButton = uiradiobutton(app.ButtonGroup);
            app.nButton.Enable = 'off';
            app.nButton.Visible = 'off';
            app.nButton.Text = 'n';
            app.nButton.Value = true;
            app.ButtonGroup_2 = uibuttongroup(app.Page1Tab);
            app.ButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_Tab1_SelectionChanged, true);
            app.Button_6 = uiradiobutton(app.ButtonGroup_2);
            app.Button_6.Text = '-2';
            app.Button_7 = uiradiobutton(app.ButtonGroup_2);
            app.Button_7.Text = '-1';
            app.Button_8 = uiradiobutton(app.ButtonGroup_2);
            app.Button_8.Text = '0';
            app.Button_9 = uiradiobutton(app.ButtonGroup_2);
            app.Button_9.Text = '1';
            app.Button_10 = uiradiobutton(app.ButtonGroup_2);
            app.Button_10.Text = '2';
            app.nButton_2 = uiradiobutton(app.ButtonGroup_2);
            app.nButton_2.Enable = 'off';
            app.nButton_2.Visible = 'off';
            app.nButton_2.Text = 'n';
            app.nButton_2.Value = true;
            app.ButtonGroup_3 = uibuttongroup(app.Page1Tab);
            app.ButtonGroup_3.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_Tab1_SelectionChanged, true);
            app.Button_11 = uiradiobutton(app.ButtonGroup_3);
            app.Button_11.Text = '-2';
            app.Button_12 = uiradiobutton(app.ButtonGroup_3);
            app.Button_12.Text = '-1';
            app.Button_13 = uiradiobutton(app.ButtonGroup_3);
            app.Button_13.Text = '0';
            app.Button_14 = uiradiobutton(app.ButtonGroup_3);
            app.Button_14.Text = '1';
            app.Button_15 = uiradiobutton(app.ButtonGroup_3);
            app.Button_15.Text = '2';
            app.nButton_3 = uiradiobutton(app.ButtonGroup_3);
            app.nButton_3.Enable = 'off';
            app.nButton_3.Visible = 'off';
            app.nButton_3.Text = 'n';
            app.nButton_3.Value = true;
            app.ButtonGroup_4 = uibuttongroup(app.Page1Tab);
            app.ButtonGroup_4.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_Tab1_SelectionChanged, true);
            app.Button_16 = uiradiobutton(app.ButtonGroup_4);
            app.Button_16.Text = '-2';
            app.Button_17 = uiradiobutton(app.ButtonGroup_4);
            app.Button_17.Text = '-1';
            app.Button_18 = uiradiobutton(app.ButtonGroup_4);
            app.Button_18.Text = '0';
            app.Button_19 = uiradiobutton(app.ButtonGroup_4);
            app.Button_19.Text = '1';
            app.Button_20 = uiradiobutton(app.ButtonGroup_4);
            app.Button_20.Text = '2';
            app.nButton_4 = uiradiobutton(app.ButtonGroup_4);
            app.nButton_4.Enable = 'off';
            app.nButton_4.Visible = 'off';
            app.nButton_4.Text = 'n';
            app.nButton_4.Value = true;
            app.ButtonGroup_5 = uibuttongroup(app.Page1Tab);
            app.ButtonGroup_5.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_Tab1_SelectionChanged, true);
            app.Button_21 = uiradiobutton(app.ButtonGroup_5);
            app.Button_21.Text = '-2';
            app.Button_22 = uiradiobutton(app.ButtonGroup_5);
            app.Button_22.Text = '-1';
            app.Button_23 = uiradiobutton(app.ButtonGroup_5);
            app.Button_23.Text = '0';
            app.Button_24 = uiradiobutton(app.ButtonGroup_5);
            app.Button_24.Text = '1';
            app.Button_25 = uiradiobutton(app.ButtonGroup_5);
            app.Button_25.Text = '2';
            app.nButton_5 = uiradiobutton(app.ButtonGroup_5);
            app.nButton_5.Enable = 'off';
            app.nButton_5.Visible = 'off';
            app.nButton_5.Text = 'n';
            app.nButton_5.Value = true;
            app.ButtonGroup_6 = uibuttongroup(app.Page1Tab);
            app.ButtonGroup_6.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_Tab1_SelectionChanged, true);
            app.Button_26 = uiradiobutton(app.ButtonGroup_6);
            app.Button_26.Text = '-2';
            app.Button_27 = uiradiobutton(app.ButtonGroup_6);
            app.Button_27.Text = '-1';
            app.Button_28 = uiradiobutton(app.ButtonGroup_6);
            app.Button_28.Text = '0';
            app.Button_29 = uiradiobutton(app.ButtonGroup_6);
            app.Button_29.Text = '1';
            app.Button_30 = uiradiobutton(app.ButtonGroup_6);
            app.Button_30.Text = '2';
            app.nButton_6 = uiradiobutton(app.ButtonGroup_6);
            app.nButton_6.Enable = 'off';
            app.nButton_6.Visible = 'off';
            app.nButton_6.Text = 'n';
            app.nButton_6.Value = true;
            app.ButtonGroup_7 = uibuttongroup(app.Page1Tab);
            app.ButtonGroup_7.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_Tab1_SelectionChanged, true);
            app.Button_31 = uiradiobutton(app.ButtonGroup_7);
            app.Button_31.Text = '-2';
            app.Button_32 = uiradiobutton(app.ButtonGroup_7);
            app.Button_32.Text = '-1';
            app.Button_33 = uiradiobutton(app.ButtonGroup_7);
            app.Button_33.Text = '0';
            app.Button_34 = uiradiobutton(app.ButtonGroup_7);
            app.Button_34.Text = '1';
            app.Button_35 = uiradiobutton(app.ButtonGroup_7);
            app.Button_35.Text = '2';
            app.nButton_7 = uiradiobutton(app.ButtonGroup_7);
            app.nButton_7.Enable = 'off';
            app.nButton_7.Visible = 'off';
            app.nButton_7.Text = 'n';
            app.nButton_7.Value = true;
            app.ButtonGroup_8 = uibuttongroup(app.Page1Tab);
            app.ButtonGroup_8.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_Tab1_SelectionChanged, true);
            app.Button_36 = uiradiobutton(app.ButtonGroup_8);
            app.Button_36.Text = '-2';
            app.Button_37 = uiradiobutton(app.ButtonGroup_8);
            app.Button_37.Text = '-1';
            app.Button_38 = uiradiobutton(app.ButtonGroup_8);
            app.Button_38.Text = '0';
            app.Button_39 = uiradiobutton(app.ButtonGroup_8);
            app.Button_39.Text = '1';
            app.Button_40 = uiradiobutton(app.ButtonGroup_8);
            app.Button_40.Text = '2';
            app.nButton_8 = uiradiobutton(app.ButtonGroup_8);
            app.nButton_8.Enable = 'off';
            app.nButton_8.Visible = 'off';
            app.nButton_8.Text = 'n';
            app.nButton_8.Value = true;
            app.ButtonGroup_9 = uibuttongroup(app.Page1Tab);
            app.ButtonGroup_9.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_Tab1_SelectionChanged, true);
            app.Button_41 = uiradiobutton(app.ButtonGroup_9);
            app.Button_41.Text = '-2';
            app.Button_42 = uiradiobutton(app.ButtonGroup_9);
            app.Button_42.Text = '-1';
            app.Button_43 = uiradiobutton(app.ButtonGroup_9);
            app.Button_43.Text = '0';
            app.Button_44 = uiradiobutton(app.ButtonGroup_9);
            app.Button_44.Text = '1';
            app.Button_45 = uiradiobutton(app.ButtonGroup_9);
            app.Button_45.Text = '2';
            app.nButton_9 = uiradiobutton(app.ButtonGroup_9);
            app.nButton_9.Enable = 'off';
            app.nButton_9.Visible = 'off';
            app.nButton_9.Text = 'n';
            app.nButton_9.Value = true;
            app.ButtonGroup_10 = uibuttongroup(app.Page1Tab);
            app.ButtonGroup_10.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_Tab1_SelectionChanged, true);
            app.Button_46 = uiradiobutton(app.ButtonGroup_10);
            app.Button_46.Text = '-2';
            app.Button_47 = uiradiobutton(app.ButtonGroup_10);
            app.Button_47.Text = '-1';
            app.Button_48 = uiradiobutton(app.ButtonGroup_10);
            app.Button_48.Text = '0';
            app.Button_49 = uiradiobutton(app.ButtonGroup_10);
            app.Button_49.Text = '1';
            app.Button_50 = uiradiobutton(app.ButtonGroup_10);
            app.Button_50.Text = '2';
            app.nButton_10 = uiradiobutton(app.ButtonGroup_10);
            app.nButton_10.Enable = 'off';
            app.nButton_10.Visible = 'off';
            app.nButton_10.Text = 'n';
            app.nButton_10.Value = true;
            app.ButtonGroup_11 = uibuttongroup(app.Page1Tab);
            app.ButtonGroup_11.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_Tab1_SelectionChanged, true);
            app.Button_51 = uiradiobutton(app.ButtonGroup_11);
            app.Button_51.Text = '-2';
            app.Button_52 = uiradiobutton(app.ButtonGroup_11);
            app.Button_52.Text = '-1';
            app.Button_53 = uiradiobutton(app.ButtonGroup_11);
            app.Button_53.Text = '0';
            app.Button_54 = uiradiobutton(app.ButtonGroup_11);
            app.Button_54.Text = '1';
            app.Button_55 = uiradiobutton(app.ButtonGroup_11);
            app.Button_55.Text = '2';
            app.nButton_11 = uiradiobutton(app.ButtonGroup_11);
            app.nButton_11.Enable = 'off';
            app.nButton_11.Visible = 'off';
            app.nButton_11.Text = 'n';
            app.nButton_11.Value = true;
            app.ButtonGroup_12 = uibuttongroup(app.Page1Tab);
            app.ButtonGroup_12.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_Tab1_SelectionChanged, true);
            app.Button_56 = uiradiobutton(app.ButtonGroup_12);
            app.Button_56.Text = '-2';
            app.Button_57 = uiradiobutton(app.ButtonGroup_12);
            app.Button_57.Text = '-1';
            app.Button_58 = uiradiobutton(app.ButtonGroup_12);
            app.Button_58.Text = '0';
            app.Button_59 = uiradiobutton(app.ButtonGroup_12);
            app.Button_59.Text = '1';
            app.Button_60 = uiradiobutton(app.ButtonGroup_12);
            app.Button_60.Text = '2';
            app.nButton_12 = uiradiobutton(app.ButtonGroup_12);
            app.nButton_12.Enable = 'off';
            app.nButton_12.Visible = 'off';
            app.nButton_12.Text = 'n';
            app.nButton_12.Value = true;
            app.ButtonGroup_13 = uibuttongroup(app.Page1Tab);
            app.ButtonGroup_13.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_Tab1_SelectionChanged, true);
            app.Button_61 = uiradiobutton(app.ButtonGroup_13);
            app.Button_61.Text = '-2';
            app.Button_62 = uiradiobutton(app.ButtonGroup_13);
            app.Button_62.Text = '-1';
            app.Button_63 = uiradiobutton(app.ButtonGroup_13);
            app.Button_63.Text = '0';
            app.Button_64 = uiradiobutton(app.ButtonGroup_13);
            app.Button_64.Text = '1';
            app.Button_65 = uiradiobutton(app.ButtonGroup_13);
            app.Button_65.Text = '2';
            app.nButton_13 = uiradiobutton(app.ButtonGroup_13);
            app.nButton_13.Enable = 'off';
            app.nButton_13.Visible = 'off';
            app.nButton_13.Text = 'n';
            app.nButton_13.Value = true;
            app.ButtonGroup_14 = uibuttongroup(app.Page1Tab);
            app.ButtonGroup_14.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_Tab1_SelectionChanged, true);
            app.Button_66 = uiradiobutton(app.ButtonGroup_14);
            app.Button_66.Text = '-2';
            app.Button_67 = uiradiobutton(app.ButtonGroup_14);
            app.Button_67.Text = '-1';
            app.Button_68 = uiradiobutton(app.ButtonGroup_14);
            app.Button_68.Text = '0';
            app.Button_69 = uiradiobutton(app.ButtonGroup_14);
            app.Button_69.Text = '1';
            app.Button_70 = uiradiobutton(app.ButtonGroup_14);
            app.Button_70.Text = '2';
            app.nButton_14 = uiradiobutton(app.ButtonGroup_14);
            app.nButton_14.Enable = 'off';
            app.nButton_14.Visible = 'off';
            app.nButton_14.Text = 'n';
            app.nButton_14.Value = true;
            app.ButtonGroup_15 = uibuttongroup(app.Page1Tab);
            app.ButtonGroup_15.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_Tab1_SelectionChanged, true);
            app.Button_71 = uiradiobutton(app.ButtonGroup_15);
            app.Button_71.Text = '-2';
            app.Button_72 = uiradiobutton(app.ButtonGroup_15);
            app.Button_72.Text = '-1';
            app.Button_73 = uiradiobutton(app.ButtonGroup_15);
            app.Button_73.Text = '0';
            app.Button_74 = uiradiobutton(app.ButtonGroup_15);
            app.Button_74.Text = '1';
            app.Button_75 = uiradiobutton(app.ButtonGroup_15);
            app.Button_75.Text = '2';
            app.nButton_15 = uiradiobutton(app.ButtonGroup_15);
            app.nButton_15.Enable = 'off';
            app.nButton_15.Visible = 'off';
            app.nButton_15.Text = 'n';
            app.nButton_15.Value = true;
            app.ButtonGroup_16 = uibuttongroup(app.Page1Tab);
            app.ButtonGroup_16.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_Tab1_SelectionChanged, true);
            app.Button_76 = uiradiobutton(app.ButtonGroup_16);
            app.Button_76.Text = '-2';
            app.Button_77 = uiradiobutton(app.ButtonGroup_16);
            app.Button_77.Text = '-1';
            app.Button_78 = uiradiobutton(app.ButtonGroup_16);
            app.Button_78.Text = '0';
            app.Button_79 = uiradiobutton(app.ButtonGroup_16);
            app.Button_79.Text = '1';
            app.Button_80 = uiradiobutton(app.ButtonGroup_16);
            app.Button_80.Text = '2';
            app.nButton_16 = uiradiobutton(app.ButtonGroup_16);
            app.nButton_16.Enable = 'off';
            app.nButton_16.Visible = 'off';
            app.nButton_16.Text = 'n';
            app.nButton_16.Value = true;
            app.Label_2 = uilabel(app.Page1Tab);
            app.Label_2.Text = '';
            app.Page2Tab = uitab(app.TabGroup);
            app.Page2Tab.Title = 'Page 2';
            app.PasdutoutdaccordLabel_2 = uilabel(app.Page2Tab);
            app.PasdutoutdaccordLabel_2.Text = 'Pas du tout d''accord';
            app.PasdaccordLabel_2 = uilabel(app.Page2Tab);
            app.PasdaccordLabel_2.Text = 'Pas d''accord';
            app.PlusoumoinsdaccordLabel_2 = uilabel(app.Page2Tab);
            app.PlusoumoinsdaccordLabel_2.Text = 'Plus ou moins d''accord';
            app.DaccordLabel_2 = uilabel(app.Page2Tab);
            app.DaccordLabel_2.Text = 'D''accord';
            app.ToutfaitdaccordLabel_2 = uilabel(app.Page2Tab);
            app.ToutfaitdaccordLabel_2.Text = 'Tout à fait d''accord';
            app.JaieudespensesquejenauraispasvolontierspartagavecdautresLabel = uilabel(app.Page2Tab);
            app.JaieudespensesquejenauraispasvolontierspartagavecdautresLabel.Text = 'J''ai eu des pensées que je n''aurais pas volontiers partagé avec d''autres.';
            app.JtaisoccuppenserLabel = uilabel(app.Page2Tab);
            app.JtaisoccuppenserLabel.Text = 'J''étais occupé à penser.';
            app.JavaisdespensessimilairestoutaulongdelasessionLabel = uilabel(app.Page2Tab);
            app.JavaisdespensessimilairestoutaulongdelasessionLabel.Text = 'J''avais des pensées similaires tout au long de la session.';
            app.JaipensauxautresLabel = uilabel(app.Page2Tab);
            app.JaipensauxautresLabel.Text = 'J''ai pensé aux autres.';
            app.JaipensmoimmeLabel = uilabel(app.Page2Tab);
            app.JaipensmoimmeLabel.Text = 'J''ai pensé à moi-même.';
            app.JaipensdeschosesagrablesLabel = uilabel(app.Page2Tab);
            app.JaipensdeschosesagrablesLabel.Text = 'J''ai pensé à des choses agréables.';
            app.JecontrlaismespensesLabel = uilabel(app.Page2Tab);
            app.JecontrlaismespensesLabel.Text = 'Je contrôlais mes pensées.';
            app.JaipensrsoudredesproblmesLabel = uilabel(app.Page2Tab);
            app.JaipensrsoudredesproblmesLabel.Text = 'J''ai pensé à résoudre des problèmes.';
            app.JaipensaubutdelexprienceLabel = uilabel(app.Page2Tab);
            app.JaipensaubutdelexprienceLabel.Text = 'J''ai pensé au but de l''expérience.';
            app.JaieudumalresterveilleLabel = uilabel(app.Page2Tab);
            app.JaieudumalresterveilleLabel.Text = 'J''ai eu du mal à rester éveillé.e.';
            app.JaieudespensesquichangaientrapidementLabel = uilabel(app.Page2Tab);
            app.JaieudespensesquichangaientrapidementLabel.Text = 'J''ai eu des pensées qui changaient rapidement.';
            app.JaieudespensessuperficiellesLabel = uilabel(app.Page2Tab);
            app.JaieudespensessuperficiellesLabel.Text = 'J''ai eu des pensées superficielles.';
            app.JaipensaupassLabel = uilabel(app.Page2Tab);
            app.JaipensaupassLabel.Text = 'J''ai pensé au passé.';
            app.JaipensauprsentLabel = uilabel(app.Page2Tab);
            app.JaipensauprsentLabel.Text = 'J''ai pensé au présent.';
            app.JaipensaufuturLabel = uilabel(app.Page2Tab);
            app.JaipensaufuturLabel.Text = 'J''ai pensé au futur.';
            app.JaieudespensesprofondesLabel = uilabel(app.Page2Tab);
            app.JaieudespensesprofondesLabel.Text = 'J''ai eu des pensées profondes.';
            app.SuivantButton_2 = uibutton(app.Page2Tab, 'push');
            app.SuivantButton_2.ButtonPushedFcn = createCallbackFcn(app, @SuivantButton_2Pushed, true);
            app.SuivantButton_2.Enable = 'off';
            app.SuivantButton_2.Text = 'Suivant';
            app.ButtonGroup_17 = uibuttongroup(app.Page2Tab);
            app.ButtonGroup_17.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_Tab2_SelectionChanged, true);
            app.Button_81 = uiradiobutton(app.ButtonGroup_17);
            app.Button_81.Text = '-2';
            app.Button_82 = uiradiobutton(app.ButtonGroup_17);
            app.Button_82.Text = '-1';
            app.Button_83 = uiradiobutton(app.ButtonGroup_17);
            app.Button_83.Text = '0';
            app.Button_84 = uiradiobutton(app.ButtonGroup_17);
            app.Button_84.Text = '1';
            app.Button_85 = uiradiobutton(app.ButtonGroup_17);
            app.Button_85.Text = '2';
            app.nButton_17 = uiradiobutton(app.ButtonGroup_17);
            app.nButton_17.Enable = 'off';
            app.nButton_17.Visible = 'off';
            app.nButton_17.Text = 'n';
            app.nButton_17.Value = true;
            app.ButtonGroup_18 = uibuttongroup(app.Page2Tab);
            app.ButtonGroup_18.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_Tab2_SelectionChanged, true);
            app.Button_86 = uiradiobutton(app.ButtonGroup_18);
            app.Button_86.Text = '-2';
            app.Button_87 = uiradiobutton(app.ButtonGroup_18);
            app.Button_87.Text = '-1';
            app.Button_88 = uiradiobutton(app.ButtonGroup_18);
            app.Button_88.Text = '0';
            app.Button_89 = uiradiobutton(app.ButtonGroup_18);
            app.Button_89.Text = '1';
            app.Button_90 = uiradiobutton(app.ButtonGroup_18);
            app.Button_90.Text = '2';
            app.nButton_18 = uiradiobutton(app.ButtonGroup_18);
            app.nButton_18.Enable = 'off';
            app.nButton_18.Visible = 'off';
            app.nButton_18.Text = 'n';
            app.nButton_18.Value = true;
            app.ButtonGroup_19 = uibuttongroup(app.Page2Tab);
            app.ButtonGroup_19.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_Tab2_SelectionChanged, true);
            app.Button_91 = uiradiobutton(app.ButtonGroup_19);
            app.Button_91.Text = '-2';
            app.Button_92 = uiradiobutton(app.ButtonGroup_19);
            app.Button_92.Text = '-1';
            app.Button_93 = uiradiobutton(app.ButtonGroup_19);
            app.Button_93.Text = '0';
            app.Button_94 = uiradiobutton(app.ButtonGroup_19);
            app.Button_94.Text = '1';
            app.Button_95 = uiradiobutton(app.ButtonGroup_19);
            app.Button_95.Text = '2';
            app.nButton_19 = uiradiobutton(app.ButtonGroup_19);
            app.nButton_19.Enable = 'off';
            app.nButton_19.Visible = 'off';
            app.nButton_19.Text = 'n';
            app.nButton_19.Value = true;
            app.ButtonGroup_20 = uibuttongroup(app.Page2Tab);
            app.ButtonGroup_20.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_Tab2_SelectionChanged, true);
            app.Button_96 = uiradiobutton(app.ButtonGroup_20);
            app.Button_96.Text = '-2';
            app.Button_97 = uiradiobutton(app.ButtonGroup_20);
            app.Button_97.Text = '-1';
            app.Button_98 = uiradiobutton(app.ButtonGroup_20);
            app.Button_98.Text = '0';
            app.Button_99 = uiradiobutton(app.ButtonGroup_20);
            app.Button_99.Text = '1';
            app.Button_100 = uiradiobutton(app.ButtonGroup_20);
            app.Button_100.Text = '2';
            app.nButton_20 = uiradiobutton(app.ButtonGroup_20);
            app.nButton_20.Enable = 'off';
            app.nButton_20.Visible = 'off';
            app.nButton_20.Text = 'n';
            app.nButton_20.Value = true;
            app.ButtonGroup_21 = uibuttongroup(app.Page2Tab);
            app.ButtonGroup_21.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_Tab2_SelectionChanged, true);
            app.Button_101 = uiradiobutton(app.ButtonGroup_21);
            app.Button_101.Text = '-2';
            app.Button_102 = uiradiobutton(app.ButtonGroup_21);
            app.Button_102.Text = '-1';
            app.Button_103 = uiradiobutton(app.ButtonGroup_21);
            app.Button_103.Text = '0';
            app.Button_104 = uiradiobutton(app.ButtonGroup_21);
            app.Button_104.Text = '1';
            app.Button_105 = uiradiobutton(app.ButtonGroup_21);
            app.Button_105.Text = '2';
            app.nButton_21 = uiradiobutton(app.ButtonGroup_21);
            app.nButton_21.Enable = 'off';
            app.nButton_21.Visible = 'off';
            app.nButton_21.Text = 'n';
            app.nButton_21.Value = true;
            app.ButtonGroup_22 = uibuttongroup(app.Page2Tab);
            app.ButtonGroup_22.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_Tab2_SelectionChanged, true);
            app.Button_106 = uiradiobutton(app.ButtonGroup_22);
            app.Button_106.Text = '-2';
            app.Button_107 = uiradiobutton(app.ButtonGroup_22);
            app.Button_107.Text = '-1';
            app.Button_108 = uiradiobutton(app.ButtonGroup_22);
            app.Button_108.Text = '0';
            app.Button_109 = uiradiobutton(app.ButtonGroup_22);
            app.Button_109.Text = '1';
            app.Button_110 = uiradiobutton(app.ButtonGroup_22);
            app.Button_110.Text = '2';
            app.nButton_22 = uiradiobutton(app.ButtonGroup_22);
            app.nButton_22.Enable = 'off';
            app.nButton_22.Visible = 'off';
            app.nButton_22.Text = 'n';
            app.nButton_22.Value = true;
            app.ButtonGroup_23 = uibuttongroup(app.Page2Tab);
            app.ButtonGroup_23.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_Tab2_SelectionChanged, true);
            app.Button_111 = uiradiobutton(app.ButtonGroup_23);
            app.Button_111.Text = '-2';
            app.Button_112 = uiradiobutton(app.ButtonGroup_23);
            app.Button_112.Text = '-1';
            app.Button_113 = uiradiobutton(app.ButtonGroup_23);
            app.Button_113.Text = '0';
            app.Button_114 = uiradiobutton(app.ButtonGroup_23);
            app.Button_114.Text = '1';
            app.Button_115 = uiradiobutton(app.ButtonGroup_23);
            app.Button_115.Text = '2';
            app.nButton_23 = uiradiobutton(app.ButtonGroup_23);
            app.nButton_23.Enable = 'off';
            app.nButton_23.Visible = 'off';
            app.nButton_23.Text = 'n';
            app.nButton_23.Value = true;
            app.ButtonGroup_24 = uibuttongroup(app.Page2Tab);
            app.ButtonGroup_24.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_Tab2_SelectionChanged, true);
            app.Button_116 = uiradiobutton(app.ButtonGroup_24);
            app.Button_116.Text = '-2';
            app.Button_117 = uiradiobutton(app.ButtonGroup_24);
            app.Button_117.Text = '-1';
            app.Button_118 = uiradiobutton(app.ButtonGroup_24);
            app.Button_118.Text = '0';
            app.Button_119 = uiradiobutton(app.ButtonGroup_24);
            app.Button_119.Text = '1';
            app.Button_120 = uiradiobutton(app.ButtonGroup_24);
            app.Button_120.Text = '2';
            app.nButton_24 = uiradiobutton(app.ButtonGroup_24);
            app.nButton_24.Enable = 'off';
            app.nButton_24.Visible = 'off';
            app.nButton_24.Text = 'n';
            app.nButton_24.Value = true;
            app.ButtonGroup_25 = uibuttongroup(app.Page2Tab);
            app.ButtonGroup_25.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_Tab2_SelectionChanged, true);
            app.Button_121 = uiradiobutton(app.ButtonGroup_25);
            app.Button_121.Text = '-2';
            app.Button_122 = uiradiobutton(app.ButtonGroup_25);
            app.Button_122.Text = '-1';
            app.Button_123 = uiradiobutton(app.ButtonGroup_25);
            app.Button_123.Text = '0';
            app.Button_124 = uiradiobutton(app.ButtonGroup_25);
            app.Button_124.Text = '1';
            app.Button_125 = uiradiobutton(app.ButtonGroup_25);
            app.Button_125.Text = '2';
            app.nButton_25 = uiradiobutton(app.ButtonGroup_25);
            app.nButton_25.Enable = 'off';
            app.nButton_25.Visible = 'off';
            app.nButton_25.Text = 'n';
            app.nButton_25.Value = true;
            app.ButtonGroup_26 = uibuttongroup(app.Page2Tab);
            app.ButtonGroup_26.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_Tab2_SelectionChanged, true);
            app.Button_126 = uiradiobutton(app.ButtonGroup_26);
            app.Button_126.Text = '-2';
            app.Button_127 = uiradiobutton(app.ButtonGroup_26);
            app.Button_127.Text = '-1';
            app.Button_128 = uiradiobutton(app.ButtonGroup_26);
            app.Button_128.Text = '0';
            app.Button_129 = uiradiobutton(app.ButtonGroup_26);
            app.Button_129.Text = '1';
            app.Button_130 = uiradiobutton(app.ButtonGroup_26);
            app.Button_130.Text = '2';
            app.nButton_26 = uiradiobutton(app.ButtonGroup_26);
            app.nButton_26.Enable = 'off';
            app.nButton_26.Visible = 'off';
            app.nButton_26.Text = 'n';
            app.nButton_26.Value = true;
            app.ButtonGroup_27 = uibuttongroup(app.Page2Tab);
            app.ButtonGroup_27.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_Tab2_SelectionChanged, true);
            app.Button_131 = uiradiobutton(app.ButtonGroup_27);
            app.Button_131.Text = '-2';
            app.Button_132 = uiradiobutton(app.ButtonGroup_27);
            app.Button_132.Text = '-1';
            app.Button_133 = uiradiobutton(app.ButtonGroup_27);
            app.Button_133.Text = '0';
            app.Button_134 = uiradiobutton(app.ButtonGroup_27);
            app.Button_134.Text = '1';
            app.Button_135 = uiradiobutton(app.ButtonGroup_27);
            app.Button_135.Text = '2';
            app.nButton_27 = uiradiobutton(app.ButtonGroup_27);
            app.nButton_27.Enable = 'off';
            app.nButton_27.Visible = 'off';
            app.nButton_27.Text = 'n';
            app.nButton_27.Value = true;
            app.ButtonGroup_28 = uibuttongroup(app.Page2Tab);
            app.ButtonGroup_28.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_Tab2_SelectionChanged, true);
            app.Button_136 = uiradiobutton(app.ButtonGroup_28);
            app.Button_136.Text = '-2';
            app.Button_137 = uiradiobutton(app.ButtonGroup_28);
            app.Button_137.Text = '-1';
            app.Button_138 = uiradiobutton(app.ButtonGroup_28);
            app.Button_138.Text = '0';
            app.Button_139 = uiradiobutton(app.ButtonGroup_28);
            app.Button_139.Text = '1';
            app.Button_140 = uiradiobutton(app.ButtonGroup_28);
            app.Button_140.Text = '2';
            app.nButton_28 = uiradiobutton(app.ButtonGroup_28);
            app.nButton_28.Enable = 'off';
            app.nButton_28.Visible = 'off';
            app.nButton_28.Text = 'n';
            app.nButton_28.Value = true;
            app.ButtonGroup_29 = uibuttongroup(app.Page2Tab);
            app.ButtonGroup_29.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_Tab2_SelectionChanged, true);
            app.Button_141 = uiradiobutton(app.ButtonGroup_29);
            app.Button_141.Text = '-2';
            app.Button_142 = uiradiobutton(app.ButtonGroup_29);
            app.Button_142.Text = '-1';
            app.Button_143 = uiradiobutton(app.ButtonGroup_29);
            app.Button_143.Text = '0';
            app.Button_144 = uiradiobutton(app.ButtonGroup_29);
            app.Button_144.Text = '1';
            app.Button_145 = uiradiobutton(app.ButtonGroup_29);
            app.Button_145.Text = '2';
            app.nButton_29 = uiradiobutton(app.ButtonGroup_29);
            app.nButton_29.Enable = 'off';
            app.nButton_29.Visible = 'off';
            app.nButton_29.Text = 'n';
            app.nButton_29.Value = true;
            app.ButtonGroup_30 = uibuttongroup(app.Page2Tab);
            app.ButtonGroup_30.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_Tab2_SelectionChanged, true);
            app.Button_146 = uiradiobutton(app.ButtonGroup_30);
            app.Button_146.Text = '-2';
            app.Button_147 = uiradiobutton(app.ButtonGroup_30);
            app.Button_147.Text = '-1';
            app.Button_148 = uiradiobutton(app.ButtonGroup_30);
            app.Button_148.Text = '0';
            app.Button_149 = uiradiobutton(app.ButtonGroup_30);
            app.Button_149.Text = '1';
            app.Button_150 = uiradiobutton(app.ButtonGroup_30);
            app.Button_150.Text = '2';
            app.nButton_30 = uiradiobutton(app.ButtonGroup_30);
            app.nButton_30.Enable = 'off';
            app.nButton_30.Visible = 'off';
            app.nButton_30.Text = 'n';
            app.nButton_30.Value = true;
            app.ButtonGroup_31 = uibuttongroup(app.Page2Tab);
            app.ButtonGroup_31.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_Tab2_SelectionChanged, true);
            app.Button_151 = uiradiobutton(app.ButtonGroup_31);
            app.Button_151.Text = '-2';
            app.Button_152 = uiradiobutton(app.ButtonGroup_31);
            app.Button_152.Text = '-1';
            app.Button_153 = uiradiobutton(app.ButtonGroup_31);
            app.Button_153.Text = '0';
            app.Button_154 = uiradiobutton(app.ButtonGroup_31);
            app.Button_154.Text = '1';
            app.Button_155 = uiradiobutton(app.ButtonGroup_31);
            app.Button_155.Text = '2';
            app.nButton_31 = uiradiobutton(app.ButtonGroup_31);
            app.nButton_31.Enable = 'off';
            app.nButton_31.Visible = 'off';
            app.nButton_31.Text = 'n';
            app.nButton_31.Value = true;
            app.ButtonGroup_32 = uibuttongroup(app.Page2Tab);
            app.ButtonGroup_32.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_Tab2_SelectionChanged, true);
            app.Button_156 = uiradiobutton(app.ButtonGroup_32);
            app.Button_156.Text = '-2';
            app.Button_157 = uiradiobutton(app.ButtonGroup_32);
            app.Button_157.Text = '-1';
            app.Button_158 = uiradiobutton(app.ButtonGroup_32);
            app.Button_158.Text = '0';
            app.Button_159 = uiradiobutton(app.ButtonGroup_32);
            app.Button_159.Text = '1';
            app.Button_160 = uiradiobutton(app.ButtonGroup_32);
            app.Button_160.Text = '2';
            app.nButton_32 = uiradiobutton(app.ButtonGroup_32);
            app.nButton_32.Enable = 'off';
            app.nButton_32.Visible = 'off';
            app.nButton_32.Text = 'n';
            app.nButton_32.Value = true;
            app.Label_20 = uilabel(app.Page2Tab);
            app.Label_20.Text = '';
            app.JenepensaisrienLabel = uilabel(app.Page2Tab);
            app.JenepensaisrienLabel.Text = 'Je ne pensais à rien.';
            app.ButtonGroup_50 = uibuttongroup(app.Page2Tab);
            app.Button_246 = uiradiobutton(app.ButtonGroup_50);
            app.Button_246.Text = '-2';
            app.Button_247 = uiradiobutton(app.ButtonGroup_50);
            app.Button_247.Text = '-1';
            app.Button_248 = uiradiobutton(app.ButtonGroup_50);
            app.Button_248.Text = '0';
            app.Button_249 = uiradiobutton(app.ButtonGroup_50);
            app.Button_249.Text = '1';
            app.Button_250 = uiradiobutton(app.ButtonGroup_50);
            app.Button_250.Text = '2';
            app.nButton_50 = uiradiobutton(app.ButtonGroup_50);
            app.nButton_50.Enable = 'off';
            app.nButton_50.Visible = 'off';
            app.nButton_50.Text = 'n';
            app.nButton_50.Value = true;
            app.Page3Tab = uitab(app.TabGroup);
            app.Page3Tab.Title = 'Page 3';
            app.PasdutoutdaccordLabel_3 = uilabel(app.Page3Tab);
            app.PasdutoutdaccordLabel_3.Text = 'Pas du tout d''accord';
            app.PasdaccordLabel_3 = uilabel(app.Page3Tab);
            app.PasdaccordLabel_3.Text = 'Pas d''accord';
            app.PlusoumoinsdaccordLabel_3 = uilabel(app.Page3Tab);
            app.PlusoumoinsdaccordLabel_3.Text = 'Plus ou moins d''accord';
            app.DaccordLabel_3 = uilabel(app.Page3Tab);
            app.DaccordLabel_3.Text = 'D''accord';
            app.ToutfaitdaccordLabel_3 = uilabel(app.Page3Tab);
            app.ToutfaitdaccordLabel_3.Text = 'Tout à fait d''accord';
            app.JaieudesdifficultsretenirmespensesLabel = uilabel(app.Page3Tab);
            app.JaieudesdifficultsretenirmespensesLabel.Text = 'J''ai eu des difficultés à retenir mes pensées.';
            app.JaipensauxpersonnesquejaimebienLabel = uilabel(app.Page3Tab);
            app.JaipensauxpersonnesquejaimebienLabel.Text = 'J''ai pensé aux personnes que j''aime bien.';
            app.JaipensenimagesLabel = uilabel(app.Page3Tab);
            app.JaipensenimagesLabel.Text = 'J''ai pensé en images.';
            app.JaipensenmotsLabel = uilabel(app.Page3Tab);
            app.JaipensenmotsLabel.Text = 'J''ai pensé en mots.';
            app.JaipensauxchosesquejedoisfaireLabel = uilabel(app.Page3Tab);
            app.JaipensauxchosesquejedoisfaireLabel.Text = 'J''ai pensé aux choses que je dois faire.';
            app.JtaisconscientdemoncorpsLabel = uilabel(app.Page3Tab);
            app.JtaisconscientdemoncorpsLabel.Text = 'J''étais conscient de mon corps.';
            app.JaipensauxsonsquimentourentLabel = uilabel(app.Page3Tab);
            app.JaipensauxsonsquimentourentLabel.Text = 'J''ai pensé aux sons qui m''entourent.';
            app.JaipensauxodeursautourdemoiLabel = uilabel(app.Page3Tab);
            app.JaipensauxodeursautourdemoiLabel.Text = 'J''ai pensé aux odeurs autour de moi.';
            app.JaipensauxbtementsdemoncoeurLabel = uilabel(app.Page3Tab);
            app.JaipensauxbtementsdemoncoeurLabel.Text = 'J''ai pensé aux bâtements de mon coeur.';
            app.JaipensmarespirationLabel = uilabel(app.Page3Tab);
            app.JaipensmarespirationLabel.Text = 'J''ai pensé à ma respiration.';
            app.JairessentideladouleurLabel = uilabel(app.Page3Tab);
            app.JairessentideladouleurLabel.Text = 'J''ai ressenti de la douleur.';
            app.JemesuismislaplacedesautresLabel = uilabel(app.Page3Tab);
            app.JemesuismislaplacedesautresLabel.Text = 'Je me suis mis à la place des autres.';
            app.JemesuissentimotivpourparticiperLabel = uilabel(app.Page3Tab);
            app.JemesuissentimotivpourparticiperLabel.Text = 'Je me suis senti motivé pour participer.';
            app.JaidesdifficultsmesouvenirdemespensesLabel = uilabel(app.Page3Tab);
            app.JaidesdifficultsmesouvenirdemespensesLabel.Text = 'J''ai des difficultés à me souvenir de mes pensées.';
            app.JaidesdifficultsmesouvenirdemessentimentsLabel = uilabel(app.Page3Tab);
            app.JaidesdifficultsmesouvenirdemessentimentsLabel.Text = 'J''ai des difficultés à me souvenir de mes sentiments.';
            app.JavaislesyeuxfermsLabel = uilabel(app.Page3Tab);
            app.JavaislesyeuxfermsLabel.Text = 'J''avais les yeux fermés.';
            app.JaipurpondrecorrectementauxaffirmationsprcdentesLabel = uilabel(app.Page3Tab);
            app.JaipurpondrecorrectementauxaffirmationsprcdentesLabel.Text = 'J''ai pu répondre correctement aux affirmations précédentes.';
            app.TerminerButton = uibutton(app.Page3Tab, 'push');
            app.TerminerButton.ButtonPushedFcn = createCallbackFcn(app, @TerminerButtonPushed, true);
            app.TerminerButton.Enable = 'off';
            app.TerminerButton.Text = 'Terminer';
            app.ButtonGroup_33 = uibuttongroup(app.Page3Tab);
            app.ButtonGroup_33.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_Tab3_SelectionChanged, true);
            app.Button_161 = uiradiobutton(app.ButtonGroup_33);
            app.Button_161.Text = '-2';
            app.Button_162 = uiradiobutton(app.ButtonGroup_33);
            app.Button_162.Text = '-1';
            app.Button_163 = uiradiobutton(app.ButtonGroup_33);
            app.Button_163.Text = '0';
            app.Button_164 = uiradiobutton(app.ButtonGroup_33);
            app.Button_164.Text = '1';
            app.Button_165 = uiradiobutton(app.ButtonGroup_33);
            app.Button_165.Text = '2';
            app.nButton_33 = uiradiobutton(app.ButtonGroup_33);
            app.nButton_33.Enable = 'off';
            app.nButton_33.Visible = 'off';
            app.nButton_33.Text = 'n';
            app.nButton_33.Value = true;
            app.ButtonGroup_34 = uibuttongroup(app.Page3Tab);
            app.ButtonGroup_34.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_Tab3_SelectionChanged, true);
            app.Button_166 = uiradiobutton(app.ButtonGroup_34);
            app.Button_166.Text = '-2';
            app.Button_167 = uiradiobutton(app.ButtonGroup_34);
            app.Button_167.Text = '-1';
            app.Button_168 = uiradiobutton(app.ButtonGroup_34);
            app.Button_168.Text = '0';
            app.Button_169 = uiradiobutton(app.ButtonGroup_34);
            app.Button_169.Text = '1';
            app.Button_170 = uiradiobutton(app.ButtonGroup_34);
            app.Button_170.Text = '2';
            app.nButton_34 = uiradiobutton(app.ButtonGroup_34);
            app.nButton_34.Enable = 'off';
            app.nButton_34.Visible = 'off';
            app.nButton_34.Text = 'n';
            app.nButton_34.Value = true;
            app.ButtonGroup_35 = uibuttongroup(app.Page3Tab);
            app.ButtonGroup_35.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_Tab3_SelectionChanged, true);
            app.Button_171 = uiradiobutton(app.ButtonGroup_35);
            app.Button_171.Text = '-2';
            app.Button_172 = uiradiobutton(app.ButtonGroup_35);
            app.Button_172.Text = '-1';
            app.Button_173 = uiradiobutton(app.ButtonGroup_35);
            app.Button_173.Text = '0';
            app.Button_174 = uiradiobutton(app.ButtonGroup_35);
            app.Button_174.Text = '1';
            app.Button_175 = uiradiobutton(app.ButtonGroup_35);
            app.Button_175.Text = '2';
            app.nButton_35 = uiradiobutton(app.ButtonGroup_35);
            app.nButton_35.Enable = 'off';
            app.nButton_35.Visible = 'off';
            app.nButton_35.Text = 'n';
            app.nButton_35.Value = true;
            app.ButtonGroup_36 = uibuttongroup(app.Page3Tab);
            app.ButtonGroup_36.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_Tab3_SelectionChanged, true);
            app.Button_176 = uiradiobutton(app.ButtonGroup_36);
            app.Button_176.Text = '-2';
            app.Button_177 = uiradiobutton(app.ButtonGroup_36);
            app.Button_177.Text = '-1';
            app.Button_178 = uiradiobutton(app.ButtonGroup_36);
            app.Button_178.Text = '0';
            app.Button_179 = uiradiobutton(app.ButtonGroup_36);
            app.Button_179.Text = '1';
            app.Button_180 = uiradiobutton(app.ButtonGroup_36);
            app.Button_180.Text = '2';
            app.nButton_36 = uiradiobutton(app.ButtonGroup_36);
            app.nButton_36.Enable = 'off';
            app.nButton_36.Visible = 'off';
            app.nButton_36.Text = 'n';
            app.nButton_36.Value = true;
            app.ButtonGroup_37 = uibuttongroup(app.Page3Tab);
            app.ButtonGroup_37.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_Tab3_SelectionChanged, true);
            app.Button_181 = uiradiobutton(app.ButtonGroup_37);
            app.Button_181.Text = '-2';
            app.Button_182 = uiradiobutton(app.ButtonGroup_37);
            app.Button_182.Text = '-1';
            app.Button_183 = uiradiobutton(app.ButtonGroup_37);
            app.Button_183.Text = '0';
            app.Button_184 = uiradiobutton(app.ButtonGroup_37);
            app.Button_184.Text = '1';
            app.Button_185 = uiradiobutton(app.ButtonGroup_37);
            app.Button_185.Text = '2';
            app.nButton_37 = uiradiobutton(app.ButtonGroup_37);
            app.nButton_37.Enable = 'off';
            app.nButton_37.Visible = 'off';
            app.nButton_37.Text = 'n';
            app.nButton_37.Value = true;
            app.ButtonGroup_38 = uibuttongroup(app.Page3Tab);
            app.ButtonGroup_38.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_Tab3_SelectionChanged, true);
            app.Button_186 = uiradiobutton(app.ButtonGroup_38);
            app.Button_186.Text = '-2';
            app.Button_187 = uiradiobutton(app.ButtonGroup_38);
            app.Button_187.Text = '-1';
            app.Button_188 = uiradiobutton(app.ButtonGroup_38);
            app.Button_188.Text = '0';
            app.Button_189 = uiradiobutton(app.ButtonGroup_38);
            app.Button_189.Text = '1';
            app.Button_190 = uiradiobutton(app.ButtonGroup_38);
            app.Button_190.Text = '2';
            app.nButton_38 = uiradiobutton(app.ButtonGroup_38);
            app.nButton_38.Enable = 'off';
            app.nButton_38.Visible = 'off';
            app.nButton_38.Text = 'n';
            app.nButton_38.Value = true;
            app.ButtonGroup_39 = uibuttongroup(app.Page3Tab);
            app.ButtonGroup_39.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_Tab3_SelectionChanged, true);
            app.Button_191 = uiradiobutton(app.ButtonGroup_39);
            app.Button_191.Text = '-2';
            app.Button_192 = uiradiobutton(app.ButtonGroup_39);
            app.Button_192.Text = '-1';
            app.Button_193 = uiradiobutton(app.ButtonGroup_39);
            app.Button_193.Text = '0';
            app.Button_194 = uiradiobutton(app.ButtonGroup_39);
            app.Button_194.Text = '1';
            app.Button_195 = uiradiobutton(app.ButtonGroup_39);
            app.Button_195.Text = '2';
            app.nButton_39 = uiradiobutton(app.ButtonGroup_39);
            app.nButton_39.Enable = 'off';
            app.nButton_39.Visible = 'off';
            app.nButton_39.Text = 'n';
            app.nButton_39.Value = true;
            app.ButtonGroup_40 = uibuttongroup(app.Page3Tab);
            app.ButtonGroup_40.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_Tab3_SelectionChanged, true);
            app.Button_196 = uiradiobutton(app.ButtonGroup_40);
            app.Button_196.Text = '-2';
            app.Button_197 = uiradiobutton(app.ButtonGroup_40);
            app.Button_197.Text = '-1';
            app.Button_198 = uiradiobutton(app.ButtonGroup_40);
            app.Button_198.Text = '0';
            app.Button_199 = uiradiobutton(app.ButtonGroup_40);
            app.Button_199.Text = '1';
            app.Button_200 = uiradiobutton(app.ButtonGroup_40);
            app.Button_200.Text = '2';
            app.nButton_40 = uiradiobutton(app.ButtonGroup_40);
            app.nButton_40.Enable = 'off';
            app.nButton_40.Visible = 'off';
            app.nButton_40.Text = 'n';
            app.nButton_40.Value = true;
            app.ButtonGroup_41 = uibuttongroup(app.Page3Tab);
            app.ButtonGroup_41.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_Tab3_SelectionChanged, true);
            app.Button_201 = uiradiobutton(app.ButtonGroup_41);
            app.Button_201.Text = '-2';
            app.Button_202 = uiradiobutton(app.ButtonGroup_41);
            app.Button_202.Text = '-1';
            app.Button_203 = uiradiobutton(app.ButtonGroup_41);
            app.Button_203.Text = '0';
            app.Button_204 = uiradiobutton(app.ButtonGroup_41);
            app.Button_204.Text = '1';
            app.Button_205 = uiradiobutton(app.ButtonGroup_41);
            app.Button_205.Text = '2';
            app.nButton_41 = uiradiobutton(app.ButtonGroup_41);
            app.nButton_41.Enable = 'off';
            app.nButton_41.Visible = 'off';
            app.nButton_41.Text = 'n';
            app.nButton_41.Value = true;
            app.ButtonGroup_42 = uibuttongroup(app.Page3Tab);
            app.ButtonGroup_42.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_Tab3_SelectionChanged, true);
            app.Button_206 = uiradiobutton(app.ButtonGroup_42);
            app.Button_206.Text = '-2';
            app.Button_207 = uiradiobutton(app.ButtonGroup_42);
            app.Button_207.Text = '-1';
            app.Button_208 = uiradiobutton(app.ButtonGroup_42);
            app.Button_208.Text = '0';
            app.Button_209 = uiradiobutton(app.ButtonGroup_42);
            app.Button_209.Text = '1';
            app.Button_210 = uiradiobutton(app.ButtonGroup_42);
            app.Button_210.Text = '2';
            app.nButton_42 = uiradiobutton(app.ButtonGroup_42);
            app.nButton_42.Enable = 'off';
            app.nButton_42.Visible = 'off';
            app.nButton_42.Text = 'n';
            app.nButton_42.Value = true;
            app.ButtonGroup_43 = uibuttongroup(app.Page3Tab);
            app.ButtonGroup_43.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_Tab3_SelectionChanged, true);
            app.Button_211 = uiradiobutton(app.ButtonGroup_43);
            app.Button_211.Text = '-2';
            app.Button_212 = uiradiobutton(app.ButtonGroup_43);
            app.Button_212.Text = '-1';
            app.Button_213 = uiradiobutton(app.ButtonGroup_43);
            app.Button_213.Text = '0';
            app.Button_214 = uiradiobutton(app.ButtonGroup_43);
            app.Button_214.Text = '1';
            app.Button_215 = uiradiobutton(app.ButtonGroup_43);
            app.Button_215.Text = '2';
            app.nButton_43 = uiradiobutton(app.ButtonGroup_43);
            app.nButton_43.Enable = 'off';
            app.nButton_43.Visible = 'off';
            app.nButton_43.Text = 'n';
            app.nButton_43.Value = true;
            app.ButtonGroup_44 = uibuttongroup(app.Page3Tab);
            app.ButtonGroup_44.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_Tab3_SelectionChanged, true);
            app.Button_216 = uiradiobutton(app.ButtonGroup_44);
            app.Button_216.Text = '-2';
            app.Button_217 = uiradiobutton(app.ButtonGroup_44);
            app.Button_217.Text = '-1';
            app.Button_218 = uiradiobutton(app.ButtonGroup_44);
            app.Button_218.Text = '0';
            app.Button_219 = uiradiobutton(app.ButtonGroup_44);
            app.Button_219.Text = '1';
            app.Button_220 = uiradiobutton(app.ButtonGroup_44);
            app.Button_220.Text = '2';
            app.nButton_44 = uiradiobutton(app.ButtonGroup_44);
            app.nButton_44.Enable = 'off';
            app.nButton_44.Visible = 'off';
            app.nButton_44.Text = 'n';
            app.nButton_44.Value = true;
            app.ButtonGroup_45 = uibuttongroup(app.Page3Tab);
            app.ButtonGroup_45.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_Tab3_SelectionChanged, true);
            app.Button_221 = uiradiobutton(app.ButtonGroup_45);
            app.Button_221.Text = '-2';
            app.Button_222 = uiradiobutton(app.ButtonGroup_45);
            app.Button_222.Text = '-1';
            app.Button_223 = uiradiobutton(app.ButtonGroup_45);
            app.Button_223.Text = '0';
            app.Button_224 = uiradiobutton(app.ButtonGroup_45);
            app.Button_224.Text = '1';
            app.Button_225 = uiradiobutton(app.ButtonGroup_45);
            app.Button_225.Text = '2';
            app.nButton_45 = uiradiobutton(app.ButtonGroup_45);
            app.nButton_45.Enable = 'off';
            app.nButton_45.Visible = 'off';
            app.nButton_45.Text = 'n';
            app.nButton_45.Value = true;
            app.ButtonGroup_46 = uibuttongroup(app.Page3Tab);
            app.ButtonGroup_46.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_Tab3_SelectionChanged, true);
            app.Button_226 = uiradiobutton(app.ButtonGroup_46);
            app.Button_226.Text = '-2';
            app.Button_227 = uiradiobutton(app.ButtonGroup_46);
            app.Button_227.Text = '-1';
            app.Button_228 = uiradiobutton(app.ButtonGroup_46);
            app.Button_228.Text = '0';
            app.Button_229 = uiradiobutton(app.ButtonGroup_46);
            app.Button_229.Text = '1';
            app.Button_230 = uiradiobutton(app.ButtonGroup_46);
            app.Button_230.Text = '2';
            app.nButton_46 = uiradiobutton(app.ButtonGroup_46);
            app.nButton_46.Enable = 'off';
            app.nButton_46.Visible = 'off';
            app.nButton_46.Text = 'n';
            app.nButton_46.Value = true;
            app.ButtonGroup_47 = uibuttongroup(app.Page3Tab);
            app.ButtonGroup_47.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_Tab3_SelectionChanged, true);
            app.Button_231 = uiradiobutton(app.ButtonGroup_47);
            app.Button_231.Text = '-2';
            app.Button_232 = uiradiobutton(app.ButtonGroup_47);
            app.Button_232.Text = '-1';
            app.Button_233 = uiradiobutton(app.ButtonGroup_47);
            app.Button_233.Text = '0';
            app.Button_234 = uiradiobutton(app.ButtonGroup_47);
            app.Button_234.Text = '1';
            app.Button_235 = uiradiobutton(app.ButtonGroup_47);
            app.Button_235.Text = '2';
            app.nButton_47 = uiradiobutton(app.ButtonGroup_47);
            app.nButton_47.Enable = 'off';
            app.nButton_47.Visible = 'off';
            app.nButton_47.Text = 'n';
            app.nButton_47.Value = true;
            app.ButtonGroup_48 = uibuttongroup(app.Page3Tab);
            app.ButtonGroup_48.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_Tab3_SelectionChanged, true);
            app.Button_236 = uiradiobutton(app.ButtonGroup_48);
            app.Button_236.Text = '-2';
            app.Button_237 = uiradiobutton(app.ButtonGroup_48);
            app.Button_237.Text = '-1';
            app.Button_238 = uiradiobutton(app.ButtonGroup_48);
            app.Button_238.Text = '0';
            app.Button_239 = uiradiobutton(app.ButtonGroup_48);
            app.Button_239.Text = '1';
            app.Button_240 = uiradiobutton(app.ButtonGroup_48);
            app.Button_240.Text = '2';
            app.nButton_48 = uiradiobutton(app.ButtonGroup_48);
            app.nButton_48.Enable = 'off';
            app.nButton_48.Visible = 'off';
            app.nButton_48.Text = 'n';
            app.nButton_48.Value = true;
            app.ButtonGroup_49 = uibuttongroup(app.Page3Tab);
            app.ButtonGroup_49.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_Tab3_SelectionChanged, true);
            app.Button_241 = uiradiobutton(app.ButtonGroup_49);
            app.Button_241.Text = '-2';
            app.Button_242 = uiradiobutton(app.ButtonGroup_49);
            app.Button_242.Text = '-1';
            app.Button_243 = uiradiobutton(app.ButtonGroup_49);
            app.Button_243.Text = '0';
            app.Button_244 = uiradiobutton(app.ButtonGroup_49);
            app.Button_244.Text = '1';
            app.Button_245 = uiradiobutton(app.ButtonGroup_49);
            app.Button_245.Text = '2';
            app.nButton_49 = uiradiobutton(app.ButtonGroup_49);
            app.nButton_49.Enable = 'off';
            app.nButton_49.Visible = 'off';
            app.nButton_49.Text = 'n';
            app.nButton_49.Value = true;
            app.attentionLabel = uilabel(app.Page3Tab);
            app.attentionLabel.Visible = 'off';
            app.attentionLabel.Text = '!Répondre à toutes les questions avant de terminer!';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ArsQ_RS(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end