classdef Participant < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        EnregistreretfermerButton    matlab.ui.control.Button
        IdentifiantLabel             matlab.ui.control.Label
        NomLabel                     matlab.ui.control.Label
        NouveauParticipantPanel      matlab.ui.container.Panel
        GenreButtonGroup             matlab.ui.container.ButtonGroup
        AutreButton_2                matlab.ui.control.RadioButton
        HButton                      matlab.ui.control.RadioButton
        FButton                      matlab.ui.control.RadioButton
        nnButton_4                   matlab.ui.control.RadioButton
        LangueMaternelleButtonGroup  matlab.ui.container.ButtonGroup
        nnButton_3                   matlab.ui.control.RadioButton
        AutreButton                  matlab.ui.control.RadioButton
        FranaiseButton               matlab.ui.control.RadioButton
        NiveauEtudesButtonGroup      matlab.ui.container.ButtonGroup
        nnButton_2                   matlab.ui.control.RadioButton
        Bac8Button                   matlab.ui.control.RadioButton
        Bac7Button                   matlab.ui.control.RadioButton
        Bac6Button                   matlab.ui.control.RadioButton
        Bac4Button                   matlab.ui.control.RadioButton
        Bac5Button                   matlab.ui.control.RadioButton
        Bac3Button                   matlab.ui.control.RadioButton
        Bac2Button                   matlab.ui.control.RadioButton
        Bac1Button                   matlab.ui.control.RadioButton
        BaccalauratButton            matlab.ui.control.RadioButton
        LateraliteButtonGroup        matlab.ui.container.ButtonGroup
        nnButton                     matlab.ui.control.RadioButton
        GaucherButton                matlab.ui.control.RadioButton
        DroitierButton               matlab.ui.control.RadioButton
        AgeEditField                 matlab.ui.control.NumericEditField
        AgeEditFieldLabel            matlab.ui.control.Label
        PrnomEditField               matlab.ui.control.EditField
        PrnomEditFieldLabel          matlab.ui.control.Label
        AjouterButton                matlab.ui.control.Button
        EmailEditField               matlab.ui.control.EditField
        EmailEditFieldLabel          matlab.ui.control.Label
        NomEditField                 matlab.ui.control.EditField
        NomEditFieldLabel            matlab.ui.control.Label
        ParticipantsListBox          matlab.ui.control.ListBox
        IDListBox                    matlab.ui.control.ListBox
    end

    
    properties (Access = private)
        path;
        participants = {};
        participant_csv_file 
        SSID;
        acronym;
        %session
    end
    
    
    methods (Access = private)
        
        function selectParticipant(app,value,IDouNom)
            if value =="" 
                app.EnregistreretfermerButton.Enable = false;
            else
                app.EnregistreretfermerButton.Enable = true; 
            end
            if IDouNom == "ID"
                app.ParticipantsListBox.Value = strcat(app.participants.Prenom(ismember(app.participants.ID,str2double(value)))," ",app.participants.Nom(ismember(app.participants.ID,str2double(value))));
                scroll(app.ParticipantsListBox,app.ParticipantsListBox.Value);
                idvalue = value;
                %app.session = app.participants.Session(ismember(app.participants.ID,str2double(value)));
            else
                app.IDListBox.Value = string(app.participants.ID(ismember(strcat(app.participants.Prenom," ",app.participants.Nom),value)));
                scroll(app.IDListBox,app.IDListBox.Value);
                idvalue=app.IDListBox.Value;
                %app.session = app.participants.Session(ismember(app.participants.Nom,value));
            end
            if str2double(idvalue)<10
                app.SSID = strcat(app.acronym,"00",idvalue);
            elseif str2double(idvalue)<100
                app.SSID = strcat(app.acronym,"0",idvalue);
            else
                app.SSID = strcat(app.acronym,idvalue);
            end       
            
            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, allowNew, acronym, data_path)
            app.acronym = acronym;
            app.path = data_path;
            if allowNew == false
                app.NouveauParticipantPanel.Enable = "off";
            end
            app.participant_csv_file = strcat(app.path,'\Participants.csv');
            if isfile(app.participant_csv_file)
                app.participants = readtable(app.participant_csv_file);
                app.ParticipantsListBox.Items =strcat(app.participants.Prenom," ",app.participants.Nom);
                app.IDListBox.Items = string(app.participants.ID);
            else
                %Nom, Prenom, ID, Email, Genre, Age, Langue Maternelle, Niveau d'études, Latéralité 
                tabrows= [["Nom","string"];["Prenom","string"];["ID","int8"];["Contact","string"];["Genre","string"];["Age","int8"];["LangueMaternelle","string"];["NiveauEtudes","string"];["Lateralite","string"]];%;["Session","int8"]];
                app.participants = table('Size',[0,size(tabrows,1)],'VariableNames',tabrows(:,1),'VariableTypes', tabrows(:,2));
                writetable(app.participants,app.participant_csv_file,"WriteRowNames",true,'Delimiter',';');
            end
        end

        % Value changed function: ParticipantsListBox
        function ParticipantsListBoxValueChanged(app, event)
            value = app.ParticipantsListBox.Value;
            app.selectParticipant(value,"Nom");
        
        end

        % Value changed function: IDListBox
        function IDListBoxValueChanged(app, event)
            value = app.IDListBox.Value;
            app.selectParticipant(value,"ID");

        end

        % Button pushed function: AjouterButton
        function AjouterButtonPushed(app, event)
            if(app.NomEditField.Value~="")
                %write Participants ID in Participants.txt 
                if  height(app.participants) == 0
                    newID=1;
                else
                    newID = max(app.participants.ID+1);     
                end                        
                
                newParticipant = {app.NomEditField.Value,app.PrnomEditField.Value,newID,app.EmailEditField.Value,app.GenreButtonGroup.SelectedObject.Text, app.AgeEditField.Value,app.LangueMaternelleButtonGroup.SelectedObject.Text,app.NiveauEtudesButtonGroup.SelectedObject.Text, app.LateraliteButtonGroup.SelectedObject.Text};
                app.participants = [app.participants;newParticipant]; % add new participant to table
                app.ParticipantsListBox.Items = strcat(app.participants.Prenom," ",app.participants.Nom);
                app.IDListBox.Items = string(app.participants.ID);
                scroll(app.ParticipantsListBox,'bottom');
                scroll(app.IDListBox,'bottom');
                
                
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app);
        end

        % Button pushed function: EnregistreretfermerButton
        function EnregistreretfermerButtonPushed(app, event)
            %p_row = find(app.participants.ID==str2double(string(app.IDListBox.Value)));
            %app.participants.Session(p_row) = app.participants.Session(p_row)+1;
            writetable(app.participants,app.participant_csv_file,"WriteRowNames",true,'Delimiter',';');   
            assignin('base','ID',app.SSID);

            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.IDListBox = uilistbox(app.UIFigure);
            app.IDListBox.Items = {};
            app.IDListBox.ValueChangedFcn = createCallbackFcn(app, @IDListBoxValueChanged, true);
            app.IDListBox.Value = {};
            app.ParticipantsListBox = uilistbox(app.UIFigure);
            app.ParticipantsListBox.Items = {};
            app.ParticipantsListBox.ValueChangedFcn = createCallbackFcn(app, @ParticipantsListBoxValueChanged, true);
            app.ParticipantsListBox.Value = {};
            app.NouveauParticipantPanel = uipanel(app.UIFigure);
            app.NouveauParticipantPanel.Title = 'Nouveau Participant';
            app.NomEditFieldLabel = uilabel(app.NouveauParticipantPanel);
            app.NomEditFieldLabel.Text = 'Nom';
            app.NomEditField = uieditfield(app.NouveauParticipantPanel, 'text');
            app.EmailEditFieldLabel = uilabel(app.NouveauParticipantPanel);
            app.EmailEditFieldLabel.Text = 'Email';
            app.EmailEditField = uieditfield(app.NouveauParticipantPanel, 'text');
            app.AjouterButton = uibutton(app.NouveauParticipantPanel, 'push');
            app.AjouterButton.ButtonPushedFcn = createCallbackFcn(app, @AjouterButtonPushed, true);
            app.AjouterButton.Text = 'Ajouter';
            app.PrnomEditFieldLabel = uilabel(app.NouveauParticipantPanel);
            app.PrnomEditFieldLabel.Text = 'Prénom';
            app.PrnomEditField = uieditfield(app.NouveauParticipantPanel, 'text');
            app.AgeEditFieldLabel = uilabel(app.NouveauParticipantPanel);
            app.AgeEditFieldLabel.Text = 'Age';
            app.AgeEditField = uieditfield(app.NouveauParticipantPanel, 'numeric');
            app.LateraliteButtonGroup = uibuttongroup(app.NouveauParticipantPanel);
            app.LateraliteButtonGroup.Title = 'Latéralité';
            app.DroitierButton = uiradiobutton(app.LateraliteButtonGroup);
            app.DroitierButton.Text = 'Droitier';
            app.GaucherButton = uiradiobutton(app.LateraliteButtonGroup);
            app.GaucherButton.Text = 'Gaucher';
            app.nnButton = uiradiobutton(app.LateraliteButtonGroup);
            app.nnButton.Enable = 'off';
            app.nnButton.Visible = 'off';
            app.nnButton.Text = 'nn';
            app.nnButton.Value = true;
            app.NiveauEtudesButtonGroup = uibuttongroup(app.NouveauParticipantPanel);
            app.NiveauEtudesButtonGroup.Title = 'Niveau d''études';
            app.BaccalauratButton = uiradiobutton(app.NiveauEtudesButtonGroup);
            app.BaccalauratButton.Text = 'Baccalauréat';
            app.Bac1Button = uiradiobutton(app.NiveauEtudesButtonGroup);
            app.Bac1Button.Text = 'Bac+1';
            app.Bac2Button = uiradiobutton(app.NiveauEtudesButtonGroup);
            app.Bac2Button.Text = 'Bac+2';
            app.Bac3Button = uiradiobutton(app.NiveauEtudesButtonGroup);
            app.Bac3Button.Text = 'Bac+3';
            app.Bac5Button = uiradiobutton(app.NiveauEtudesButtonGroup);
            app.Bac5Button.Text = 'Bac+5';
            app.Bac4Button = uiradiobutton(app.NiveauEtudesButtonGroup);
            app.Bac4Button.Text = 'Bac+4';
            app.Bac6Button = uiradiobutton(app.NiveauEtudesButtonGroup);
            app.Bac6Button.Text = 'Bac+6';
            app.Bac7Button = uiradiobutton(app.NiveauEtudesButtonGroup);
            app.Bac7Button.Text = 'Bac+7';
            app.Bac8Button = uiradiobutton(app.NiveauEtudesButtonGroup);
            app.Bac8Button.Text = 'Bac+8';
            app.nnButton_2 = uiradiobutton(app.NiveauEtudesButtonGroup);
            app.nnButton_2.Enable = 'off';
            app.nnButton_2.Visible = 'off';
            app.nnButton_2.Text = 'nn';
            app.nnButton_2.Value = true;
            app.LangueMaternelleButtonGroup = uibuttongroup(app.NouveauParticipantPanel);
            app.LangueMaternelleButtonGroup.Title = 'Langue Maternelle';
            app.FranaiseButton = uiradiobutton(app.LangueMaternelleButtonGroup);
            app.FranaiseButton.Text = 'Française';
            app.AutreButton = uiradiobutton(app.LangueMaternelleButtonGroup);
            app.AutreButton.Text = 'Autre';
            app.nnButton_3 = uiradiobutton(app.LangueMaternelleButtonGroup);
            app.nnButton_3.Enable = 'off';
            app.nnButton_3.Visible = 'off';
            app.nnButton_3.Text = 'nn';
            app.nnButton_3.Value = true;
            app.GenreButtonGroup = uibuttongroup(app.NouveauParticipantPanel);
            app.GenreButtonGroup.Title = 'Genre';
            app.nnButton_4 = uiradiobutton(app.GenreButtonGroup);
            app.nnButton_4.Enable = 'off';
            app.nnButton_4.Visible = 'off';
            app.nnButton_4.Text = 'nn';
            app.nnButton_4.Value = true;
            app.FButton = uiradiobutton(app.GenreButtonGroup);
            app.FButton.Text = 'F';
            app.HButton = uiradiobutton(app.GenreButtonGroup);
            app.HButton.Text = 'H';
            app.AutreButton_2 = uiradiobutton(app.GenreButtonGroup);
            app.AutreButton_2.Text = 'Autre';
            app.NomLabel = uilabel(app.UIFigure);
            app.NomLabel.Text = 'Nom';
            app.IdentifiantLabel = uilabel(app.UIFigure);
            app.IdentifiantLabel.Text = 'Identifiant';
            app.EnregistreretfermerButton = uibutton(app.UIFigure, 'push');
            app.EnregistreretfermerButton.ButtonPushedFcn = createCallbackFcn(app, @EnregistreretfermerButtonPushed, true);
            app.EnregistreretfermerButton.Enable = 'off';
            app.EnregistreretfermerButton.Text = 'Enregistrer et fermer';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Participant(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end